classdef TSADPlatform < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        TSADPlatformUIFigure            matlab.ui.Figure
        MainPanel                       matlab.ui.container.TabGroup
        DatasetPreparationTab           matlab.ui.container.Tab
        DatasetPreparationPanel         matlab.ui.container.Panel
        DP_DatasetProcessingPanel       matlab.ui.container.Panel
        DP_AugmentationIntensityLabel   matlab.ui.control.Label
        DP_AugmentationModeDropDown     matlab.ui.control.DropDown
        AugmentationModeDropDownLabel   matlab.ui.control.Label
        DP_PreprocessingMethodDropDown  matlab.ui.control.DropDown
        PreprocessingMethodLabel        matlab.ui.control.Label
        DP_AugmentationIntensitySlider  matlab.ui.control.Slider
        DP_AugmentedTrainingCheckBock   matlab.ui.control.CheckBox
        DP_RatioTestSwitchLabel         matlab.ui.control.Label
        DP_RatioTestSwitchEditField     matlab.ui.control.NumericEditField
        DP_SplitTestSetForDynamicSwitchCheckBox  matlab.ui.control.CheckBox
        DP_RatioTestValDataLabel        matlab.ui.control.Label
        DP_SplitTestSetForThresholdingCheckBox  matlab.ui.control.CheckBox
        DP_RatioTestValDataEditField    matlab.ui.control.NumericEditField
        DP_DatasetSelectionPanel        matlab.ui.container.Panel
        DP_SelectDatasetDropDown        matlab.ui.control.DropDown
        SelectDatasetDropDownLabel      matlab.ui.control.Label
        DP_DatasetFolderEditField       matlab.ui.control.EditField
        DP_DatasetFolderLabel           matlab.ui.control.Label
        DP_BrowseDatasetButton          matlab.ui.control.Button
        DP_LoadDataButton               matlab.ui.control.Button
        DP_DatasetAttributesUITable     matlab.ui.control.Table
        DP_DatasetAttributesLabel       matlab.ui.control.Label
        DP_SelectedFileTestValDataDropDown  matlab.ui.control.DropDown
        SelectedFileLabel_5             matlab.ui.control.Label
        DP_SelectedChannelTestValDatalDropDown  matlab.ui.control.DropDown
        SelectedChannelLabel_5          matlab.ui.control.Label
        DP_SelectedFileTestDataDropDown  matlab.ui.control.DropDown
        SelectedFileLabel_4             matlab.ui.control.Label
        DP_SelectedChannelTestDataDropDown  matlab.ui.control.DropDown
        SelectedChannelLabel_4          matlab.ui.control.Label
        DP_SelectedFileTrainDataDropDown  matlab.ui.control.DropDown
        SelectedFileLabel_3             matlab.ui.control.Label
        DP_SelectedChannelTrainDataDropDown  matlab.ui.control.DropDown
        SelectedChannelLabel_3          matlab.ui.control.Label
        DP_TestValDataPreviewUIAxes     matlab.ui.control.UIAxes
        DP_TestDataPreviewUIAxes        matlab.ui.control.UIAxes
        DP_TrainingDataPreviewUIAxes    matlab.ui.control.UIAxes
        TrainingAndDetectionTab         matlab.ui.container.Tab
        TrainingAndDetectionPanel       matlab.ui.container.Panel
        TD_DetectionPanel               matlab.ui.container.Panel
        TD_DynamicThresholdSettingsPanel  matlab.ui.container.Panel
        TD_AnomalyPaddingSlider         matlab.ui.control.Slider
        TD_WindowSizeSlider             matlab.ui.control.Slider
        TD_ZRangeSlider                 matlab.ui.control.Slider
        TD_AnomalyPaddingLabel          matlab.ui.control.Label
        TD_WindowSizeLabel              matlab.ui.control.Label
        TD_ZRangeLabel                  matlab.ui.control.Label
        TD_OnlineSimulationCheckBox     matlab.ui.control.CheckBox
        TD_UpdateThresholdOnlyCheckBox  matlab.ui.control.CheckBox
        TD_GetWindowComputationTimeCheckBox  matlab.ui.control.CheckBox
        TD_ModelSelectionButtonGroup    matlab.ui.container.ButtonGroup
        TD_ModelSelectionSelectionButton  matlab.ui.control.RadioButton
        TD_ModelSelectionAllButton      matlab.ui.control.RadioButton
        TD_ThresholdDropDown            matlab.ui.control.DropDown
        ThresholdDropDownLabel          matlab.ui.control.Label
        TD_RunDetectionButton           matlab.ui.control.Button
        TD_FileSelectionButtonGroup     matlab.ui.container.ButtonGroup
        TD_FileSelectionFileDropDown    matlab.ui.control.DropDown
        TD_FileSelectionFileLabel       matlab.ui.control.Label
        TD_FileSelectionSelectionButton  matlab.ui.control.RadioButton
        TD_FileSelectionAllFilesButton  matlab.ui.control.RadioButton
        TD_FileSelectionTinyDataPreviewUIAxes  matlab.ui.control.UIAxes
        TD_DetectionTabGroup            matlab.ui.container.TabGroup
        TD_PlotsTab                     matlab.ui.container.Tab
        TD_DetectionPerformanceMetricsLabel  matlab.ui.control.Label
        TD_DetectionShownForLabel       matlab.ui.control.Label
        TD_PlotsScoresUITable           matlab.ui.control.Table
        TD_PlotsDataUIAxes              matlab.ui.control.UIAxes
        TD_PlotsAnomalyScoresUIAxes     matlab.ui.control.UIAxes
        TD_TableTab                     matlab.ui.container.Tab
        TD_ScoresExportTableButton      matlab.ui.control.Button
        TD_ScoresUITable                matlab.ui.control.Table
        TD_AverageScoresTab             matlab.ui.container.Tab
        TD_AverageScoresExportTableButton  matlab.ui.control.Button
        TD_AverageScoresUITable         matlab.ui.control.Table
        TD_WindowComputationTimeTab     matlab.ui.container.Tab
        TD_WindowComputationTimeMetricsLabel  matlab.ui.control.Label
        TP_WindowComputationTimeMetricsTree  matlab.ui.container.CheckBoxTree
        TD_WindowComputationTimeUIAxes  matlab.ui.control.UIAxes
        TD_TrainingPanel                matlab.ui.container.Panel
        TD_TrainAndTestAllButton        matlab.ui.control.Button
        TD_ExportConfigButton           matlab.ui.control.Button
        TD_DeleteAllButton              matlab.ui.control.Button
        TD_FitAllButton                 matlab.ui.control.Button
        TD_FitSelectionButton           matlab.ui.control.Button
        TD_TrainingPlotsCheckBox        matlab.ui.control.CheckBox
        TD_OptimizeCheckBox             matlab.ui.control.CheckBox
        TD_LoadManuallyButton           matlab.ui.control.Button
        TD_LoadDefaultButton            matlab.ui.control.Button
        TD_LoadConfigButton             matlab.ui.control.Button
        TD_SortByDropDown               matlab.ui.control.DropDown
        SortbyLabel                     matlab.ui.control.Label
        TD_ImportModelsButton           matlab.ui.control.Button
        TD_ExportModelsButton           matlab.ui.control.Button
        TD_ModelsListBox                matlab.ui.control.ListBox
        DynamicSwitchTab                matlab.ui.container.Tab
        DynamicSwitchPanel              matlab.ui.container.Panel
        DS_SelectedFileTestSwitchDataDropDown  matlab.ui.control.DropDown
        SelectedFileLabel_6             matlab.ui.control.Label
        DS_SelectedChannelTestSwitchDataDropDown  matlab.ui.control.DropDown
        SelectedChannelLabel_6          matlab.ui.control.Label
        DS_ExportButton                 matlab.ui.control.Button
        DS_TrainingAndEvaluationPanel   matlab.ui.container.Panel
        DS_TestingDataPredictionsLabel  matlab.ui.control.Label
        DS_TestingDataPredictionsUITable  matlab.ui.control.Table
        DS_TrainingDataLabelsLabel      matlab.ui.control.Label
        DS_TrainingDataLabelsUITable    matlab.ui.control.Table
        DS_FitButton                    matlab.ui.control.Button
        DS_EvaluateButton               matlab.ui.control.Button
        DS_ResultsLabel                 matlab.ui.control.Label
        DS_ResultsUITable               matlab.ui.control.Table
        DS_TestSwitchDataPreviewUIAxes  matlab.ui.control.UIAxes
        TD_ContextMenu                  matlab.ui.container.ContextMenu
        TD_ViewUpdateModelParametersMenu  matlab.ui.container.Menu
        TD_ShowNetworkArchitectureMenu  matlab.ui.container.Menu
        TD_ShowDetectionMenu            matlab.ui.container.Menu
        TD_RecomputeSupervisedStaticThresholdsMenu  matlab.ui.container.Menu
        TD_DeleteMenu                   matlab.ui.container.Menu
    end

    
    properties (Access = private)
        %% App Status Information
        info_dataLoaded = false                     % True if data was loaded
        info_trainDataLoaded = false;               % True if train data was loaded
        info_testDataLoaded = false;                % True if test data was loaded
        info_numTrainFiles = 0;                     % Number of training files
        info_numTestFiles = 0;                      % Number of all testing files
        info_numChannels = false;                   % Number of channels in dataset
        info_detectionShowing = false;              % True if a detection is shown in the plots on the detection panel
        info_dimensionMismatchDetected = false;     % true if dataset diemsnion doesn't match dimension of model in runDetectionFor function.
                                                    % This is only used for
                                                    % uialert message handling
        %%

        %% Data
        data_pathName                               % Parent folder of dataset
        data_channelNames                           % Names of the channels

        data_dataTrainRaw                           % Initial fault free raw data
        data_dataTestRaw                            % Initial faulty raw data
        

        data_fileNamesTrain                         % Array of filenames of the training data
        data_fileNamesTestAll                       % Array of filenames of the entire testing dataset
        data_fileNamesTest                          % Array of filenames of the final testing data (after splitting the dataset) 
        data_fileNamesTestVal                       % Array of filenames of the anomalous validation data
        data_fileNamesTestSwitch                    % Array of filenames of the dynamic switch data
        data_allTestFileNames                       % Array of filenames of all test files without the dynamic switch files

        data_labelsTrain                            % Training labels   
        data_labelsTestAll                          % Labels for all test files
        data_labelsTest                             % Labels for final testing data (after splitting the dataset) 
        data_labelsTestVal                          % Labels for anomalous validation data
        data_labelsTestSwitch                       % Labels for dynamic switch data
        data_allTestLabels                          % Labels for all test files without the dynamic switch labels

        data_dataTrain                              % Preprocessed fault free data
        data_dataTestAll                            % All testing data
        data_dataTest                               % Final testing data (after splitting the dataset) 
        data_dataTestVal                            % Anomalous validation data
        data_dataTestSwitch                         % Dynamic switch test data
        data_allTestData                            % All test data without the dynamic switch data
        
        % These timestamps aren't used in the platform, but could be of interest for
        % future upgrades
        data_timestampsTrain                        % Timestamps of training data
        data_timestampsTestAll                      % Timestamps of all test data
        data_timestampsTest                         % Timestamps of the final testing data (after splitting the dataset) 
        data_timestampsTestSwitch                   % Timestamps of the dynamic switch data
        data_timestampsTestVal                      % Timestamps of the anoamlous validation data
        data_allTestTimestamps                      % Timestamps of all test files without the dynmaic switch data

        % Channel-wise dataset features
        data_maximum                                % Maximum values of data
        data_minimum                                % Minimum values of data
        data_mean                                   % Means of training data
        data_standardDeviation                      % Standard deviations data
        %%

        %% Models
        Models = struct();%new

        %% Settings
        settings_parallelEnabled = false;           % If true, training will be run in parallel
        settings_dynamicThreshold                   % Dynamic threshold settings

        %% Detection
        currentDetection_modelID                    % Contains modelID for model that is currently shown in the plots on the detection panel
        
        fullScores = struct();                      % All scores for all tested files
        averageScores = table();                    % Average scores for all models for all files
        
        detectionStorage = struct();                % Stores all test anomaly scores, timeSeriesTest (tested time series) labels and thresholds for each file and model the detection has been run for
        %%
        
        %% Dynamic Switch
        dynamicSwitch                               % Object containing the dynamic switch classifier

        %% Popup Apps
        popup_ModelSelection                        % Popup for configuring models
        popup_ModelParametersViewAndUpdate          % Popup for viewing and updating model parameters
        popup_OptimizationConfigurator              % Popup for optimizing models
        popup_AutoRunConfigurator                   % Popup for auto run
        %%

        %% UI dialog
        progressDialog
        %%
    end
    
    properties (Access = public)
    end
    
    methods (Access = private)
%% Toggle functions for disabling/enabling elements

        function disableAxesFor(~, UIAxes)
            %DISABLEAXES Disables axes for UIAxes

            for i = 1:numel(UIAxes)
                axis(UIAxes(i), "off");
            end
        end

        function initSortbyDropdown(app)
            %INITSORTBYDROPDOWNS Init sortby dropdown menu items on detection panel

            app.TD_SortByDropDown.Items = METRIC_NAMES;
        end

        function initThresholdDropdowns(app)
            %INITTHRESHOLDDROPDOWNS Init threshold dropdown items

            items = THRESHOLD_NAMES;
            app.TD_ThresholdDropDown.Items = items;
        end

        function initDynamicThresholdSettings(app)
            %INITDYNAMICTHRESHOLDSETTINGS Init dynamic threshold settings

            app.settings_dynamicThreshold.windowSize = 50;
            app.settings_dynamicThreshold.anomalyPadding = 30;
            app.settings_dynamicThreshold.zRange = 5;
            app.settings_dynamicThreshold.minPercent = 1;

            app.TD_WindowSizeSlider.Value = app.settings_dynamicThreshold.windowSize;
            app.TD_AnomalyPaddingSlider.Value = app.settings_dynamicThreshold.anomalyPadding;
            app.TD_ZRangeSlider.Value = app.settings_dynamicThreshold.zRange;
        end

        function initWindowComputationTimeMetricNodes(app)
            %INITWINDOWCOMPUTATIONTIMEMETRICNODES Adds one node for every metric to the
            %metric selection tree in the computation time section

            metrics = METRIC_NAMES;
            for i = 1:numel(metrics)
                uitreenode(app.TP_WindowComputationTimeMetricsTree, Text=metrics(i));
            end

            expand(app.TP_WindowComputationTimeMetricsTree);
        end

        
        function toggleDynamicSwitchPanel(app, state)
            %TOGGLEDYNAMICSWITCHPANEL Toggles the dynamic switch panel

            if state
                app.DS_FitButton.Enable = "on";
                app.DS_EvaluateButton.Enable = "on";
            else
                app.DS_FitButton.Enable = "off";
                app.DS_EvaluateButton.Enable = "off";
            end
        end

        function toggleDynamicThresholdSliders(app, threshold)
            %TOGGLEDYNAMICTHRESHOLDSLIDERS Toggles the sliders for configuring the dynamic threshold

            if strcmp(threshold, "Dynamic")
                app.TD_AnomalyPaddingSlider.Enable = "on";
                app.TD_AnomalyPaddingLabel.Enable = "on";
                app.TD_WindowSizeSlider.Enable = "on";
                app.TD_WindowSizeLabel.Enable = "on";
                app.TD_ZRangeSlider.Enable = "on";
                app.TD_ZRangeLabel.Enable = "on";
            else
                app.TD_AnomalyPaddingSlider.Enable = "off";
                app.TD_AnomalyPaddingLabel.Enable = "off";
                app.TD_WindowSizeSlider.Enable = "off";
                app.TD_WindowSizeLabel.Enable = "off";
                app.TD_ZRangeSlider.Enable = "off";
                app.TD_ZRangeLabel.Enable = "off";
            end
        end

%% Dataset preview plotting functions
        
        function plotLoadedData(app, dataset, file_idx, channel_idx)
            %PLOTLOADEDDATA Plotting of dataset

            switch dataset
                case "train"
                    axes = app.DP_TrainingDataPreviewUIAxes;
                    data = app.data_dataTrain{file_idx}(:, channel_idx);
                case "test"
                    axes = app.DP_TestDataPreviewUIAxes;
                    data = app.data_dataTest{file_idx}(:, channel_idx);
                case "test-val"
                    axes = app.DP_TestValDataPreviewUIAxes;
                    data = app.data_dataTestVal{file_idx}(:, channel_idx);
                case "test-switch"
                    axes = app.DS_TestSwitchDataPreviewUIAxes;
                    data = app.data_dataTestSwitch{file_idx}(:, channel_idx);
            end
            
            cla(axes);
            plot(axes, data);
        end

        function updatePreviewData(app, dataset)
            %UPDATEPREVIEWDATA Update plots of dataset

            switch dataset
                case "train"
                    file = app.DP_SelectedFileTrainDataDropDown.Value;
                    [~, file_idx] = ismember(file, app.DP_SelectedFileTrainDataDropDown.Items);
                    channel = app.DP_SelectedChannelTrainDataDropDown.Value;
                    [~, channel_idx] = ismember(channel, app.DP_SelectedChannelTrainDataDropDown.Items);
                case "test"
                    file = app.DP_SelectedFileTestDataDropDown.Value;
                    [~, file_idx] = ismember(file, app.DP_SelectedFileTestDataDropDown.Items);
                    channel = app.DP_SelectedChannelTestDataDropDown.Value;
                    [~, channel_idx] = ismember(channel, app.DP_SelectedChannelTestDataDropDown.Items);
                case "test-val"
                    file = app.DP_SelectedFileTestValDataDropDown.Value;
                    [~, file_idx] = ismember(file, app.DP_SelectedFileTestValDataDropDown.Items);
                    channel = app.DP_SelectedChannelTestValDatalDropDown.Value;
                    [~, channel_idx] = ismember(channel, app.DP_SelectedChannelTestValDatalDropDown.Items);
                case "test-switch"
                    file = app.DS_SelectedFileTestSwitchDataDropDown.Value;
                    [~, file_idx] = ismember(file, app.DS_SelectedFileTestSwitchDataDropDown.Items);
                    channel = app.DS_SelectedChannelTestSwitchDataDropDown.Value;
                    [~, channel_idx] = ismember(channel, app.DS_SelectedChannelTestSwitchDataDropDown.Items);
            end

            app.plotLoadedData(dataset, file_idx, channel_idx);
            app.createPreviewLegend(dataset, file_idx);
        end

        function createPreviewLegend(app, dataset, file_idx)
            %CREATEPREVIEWLEGEND Creates legend for dataset axes

            switch dataset
                case "train"
                    axes = app.DP_TrainingDataPreviewUIAxes;
                    anomalies_indices = getAnomalyIndices(app.data_labelsTrain{file_idx});
                case "test"
                    axes = app.DP_TestDataPreviewUIAxes;
                    anomalies_indices = getAnomalyIndices(app.data_labelsTest{file_idx});
                case "test-val"
                    axes = app.DP_TestValDataPreviewUIAxes;
                    anomalies_indices = getAnomalyIndices(app.data_labelsTestVal{file_idx});
                case "test-switch"
                    axes = app.DS_TestSwitchDataPreviewUIAxes;
                    anomalies_indices = getAnomalyIndices(app.data_labelsTestSwitch{file_idx});
            end
            
            YLims = get(axes, "YLim");
            limits = [];
            for i = 1:size(anomalies_indices, 2)
                limits(1, i) = YLims(1);
                limits(2, i) = YLims(1);
                limits(3, i) = YLims(2);
                limits(4, i) = YLims(2);
            end
            
            if ~isempty(anomalies_indices) && ~isempty(limits)
                patch(axes, anomalies_indices, limits, "red", "EdgeColor", "none", "FaceAlpha", 0.3);
                legend(axes, "Data", "Anomalies");
            else
                legend(axes, "Data");
            end
        end   

%% Helper functions

        function highlightTableMaxima(~, UITable, rowIncrement)
            %HIGHLIGHTTABLEMAXIMA Highlights maxima in table

            if ~exist("rowIncrement", "var")
                rowIncrement = 0;
            end

            scoreMatrix = UITable.Data{(1 + rowIncrement):end, 2:end};

            % Add bold highlight for rowise maxima
            styleBold = uistyle;
            styleBold.FontWeight = "bold";
            
            rows_max_rowwise = [];
            columns_max_rowwise = [];

            for i = 1:size(scoreMatrix, 1)
                [~, col] = find(scoreMatrix(i, :) == max(scoreMatrix(i, :), [], 2));
                
                for j = 1:numel(col)
                    rows_max_rowwise = [rows_max_rowwise; i];
                    columns_max_rowwise = [columns_max_rowwise; col(j) + 1];
                end
            end
            
            if ~isempty(rows_max_rowwise) && ~isempty(columns_max_rowwise)
                rows_max_rowwise = rows_max_rowwise + rowIncrement;
                addStyle(UITable, styleBold, "cell", [rows_max_rowwise, columns_max_rowwise]);
            end
        end
        
%% Dataset preparation panel functions
        function onDatasetEditFieldValueChanged(app)
            %ONDATASETEDITFIELDVALUECHANGED Handles the dataset dropdown if
            %another dataset path is entered/selected. It detects if a
            %folder is a dataset by checking if the train or test folders
            %exist within it or within its subfolders

            path = app.DP_DatasetFolderEditField.Value;

            datasetPath_split = strsplit(path, filesep);
            datasetName = datasetPath_split{end};
                            
            if exist(fullfile(path, "test"), "dir") || exist(fullfile(path, "train"), "dir")
                % For single entity datasets
                app.DP_SelectDatasetDropDown.Items = {datasetName};

                % Set dataset path do parent folder name
                app.data_pathName = fileparts(path);
            else
                % For multiple entity datasets get all subset names
                % first
                d = dir(path);
                subFolders = d([d(:).isdir]);
                subFolders = subFolders(~ismember({subFolders(:).name},{'.', '..'}));

                subsetNames = {};

                for i = 1:numel(subFolders)
                    if exist(fullfile(path, subFolders(i).name, "test"), "dir") || exist(fullfile(path, subFolders(i).name, "train"), "dir")
                        subsetNames = [subsetNames; subFolders(i).name];
                    end
                end

                app.DP_SelectDatasetDropDown.Items = subsetNames;
                
                % Set dataset path
                app.data_pathName = path;
            end
        end
        function loadDataset(app, path)
            %LOADDATASET Loads a dataset

            [app.data_dataTrainRaw, ...
                 app.data_timestampsTrain, ...
                 app.data_labelsTrain, ...
                 app.data_fileNamesTrain, ...
                 app.data_dataTestRaw, ...
                 app.data_allTestTimestamps, ...
                 app.data_allTestLabels, ...
                 app.data_allTestFileNames, ...
                 app.data_channelNames] = loadCustomDataset(path);
            
            % Reset data related elements
            app.clearDatasetPlots();
            app.clearDynamicSwitchData();
            app.clearDetectionPlots();

            % Empty detection storage
            app.resetDetectionStorage();

            app.info_numTrainFiles = numel(app.data_fileNamesTrain);
            app.info_numTestFiles = numel(app.data_allTestFileNames);
            
            % Enable / disable dynamic switch depending of number of
            % testing files
            if app.info_numTestFiles < 2
                app.DP_SplitTestSetForDynamicSwitchCheckBox.Enable = "off";
                app.DP_RatioTestSwitchEditField.Enable = "off";
                app.DP_RatioTestSwitchLabel.Enable = "off";
                app.DP_SplitTestSetForDynamicSwitchCheckBox.Value = false;
                app.toggleDynamicSwitchPanel(false);
            else
                app.DP_SplitTestSetForDynamicSwitchCheckBox.Enable = "on";
                if app.DP_SplitTestSetForDynamicSwitchCheckBox.Value
                    app.DP_RatioTestSwitchEditField.Enable = "on";
                    app.DP_RatioTestSwitchLabel.Enable = "on";
                    app.toggleDynamicSwitchPanel(true);
                else
                    app.DP_RatioTestSwitchEditField.Enable = "off";
                    app.DP_RatioTestSwitchLabel.Enable = "off";
                    app.toggleDynamicSwitchPanel(false);
                end
            end
            
            % init train data info and data preparation plots
            if ~isempty(app.data_dataTrainRaw)
                app.info_numChannels = size(app.data_dataTrainRaw{1}, 2);

                app.DP_SelectedFileTrainDataDropDown.Items = app.data_fileNamesTrain;
                
                app.info_dataLoaded = true;
                app.info_trainDataLoaded = true;
            else
                app.info_trainDataLoaded = false;
            end
            
            % init test data info and data preparation plots
            if ~isempty(app.data_dataTestRaw)
                app.info_numChannels = size(app.data_dataTestRaw{1}, 2);

                app.DP_SelectedFileTestDataDropDown.Items = app.data_allTestFileNames;

                app.info_dataLoaded = true;
                app.info_testDataLoaded = true;
            else
                app.info_testDataLoaded = false;
            end
        end

        function resetDetectionStorage(app)
            %RESETDETECTIONSTORAGE Empties detection storage

            app.detectionStorage = struct();
            app.fullScores = struct();
        end
        
        function clearDatasetPlots(app)
            %CLEARDATASETPLOTS Reset data related plots

            cla(app.DP_TrainingDataPreviewUIAxes);
            cla(app.DP_TestDataPreviewUIAxes);
            cla(app.DP_TestValDataPreviewUIAxes);
            cla(app.DS_TestSwitchDataPreviewUIAxes);
        end

        function clearDynamicSwitchData(app)
            %CLEARDYNAMICSWITCHDATA Clear data relvenat for dynamic switch

            app.DS_TrainingDataLabelsUITable.Data = [];
            app.DS_TestingDataPredictionsUITable.Data = [];
        end

        function preprocessAndSplitDataset(app, preprocessingEnabled)
            %PREPROCESSANDSPLITDATASET Processes the entire dataset and
            %splits it into specified subsets
            %
            % If just dataset splitting is updated set preprocessingEnabled
            % to false

            if ~app.info_dataLoaded
                return;
            end
           
            
            % Preprocess data if enabled
            if preprocessingEnabled
                % All preprocessing steps except for dataset splitting
                % tasks are executed in here. preprocessingEnabled must be
                % set to true to enable this!
                
                preprocessingMethod = app.DP_PreprocessingMethodDropDown.Value;
                
                % Preprocessing
                [app.data_dataTrain, app.data_allTestData, ~] = preprocessData(app.data_dataTrainRaw, ...
                                                                               app.data_dataTestRaw, ...
                                                                               preprocessingMethod, false, []);
                
                % Augmentation
                [app.data_dataTrain, app.data_allTestData] = augmentData(app.data_dataTrain, ...
                                                                         app.data_allTestData, ...
                                                                         app.DP_AugmentationModeDropDown.Value, ...
                                                                         app.DP_AugmentationIntensitySlider.Value, ...
                                                                         app.DP_AugmentedTrainingCheckBock.Value);
            end

            
            % Split data into subsets
            if app.DP_SplitTestSetForDynamicSwitchCheckBox.Value && app.info_numTestFiles > 1
                ratioSwitch = app.DP_RatioTestSwitchEditField.Value;
                [app.data_dataTestAll, ...
                    app.data_labelsTestAll, ...
                    app.data_fileNamesTestAll, ...
                    app.data_dataTestSwitch, ...
                    app.data_labelsTestSwitch, ...
                    app.data_fileNamesTestSwitch] = splitDatasetForDynamicSwitch(app.data_allTestData, ...
                                                                                 app.data_allTestLabels, ...
                                                                                 app.data_allTestFileNames, ...
                                                                                 ratioSwitch);
            else
                app.data_dataTestAll = app.data_allTestData;
                app.data_labelsTestAll = app.data_allTestLabels;
                app.data_fileNamesTestAll = app.data_allTestFileNames;

                app.data_dataTestSwitch = [];
                app.data_labelsTestSwitch = [];
                app.data_fileNamesTestSwitch = [];
            end

            if app.DP_SplitTestSetForThresholdingCheckBox.Value
                ratioTestVal = app.DP_RatioTestValDataEditField.Value;
            else
                ratioTestVal = 0;
            end

            
            if ~isempty(app.data_dataTestAll)
                [app.data_dataTest, ...
                    app.data_labelsTest, ...
                    app.data_fileNamesTest, ...
                    app.data_dataTestVal, ...
                    app.data_labelsTestVal, ...                    
                    app.data_fileNamesTestVal] = splitTestVal(app.data_dataTestAll, ...
                                                              app.data_labelsTestAll, ...
                                                              app.data_fileNamesTestAll, ...
                                                              ratioTestVal);
            else
                app.data_dataTest = [];
                app.data_labelsTest = [];
                app.data_fileNamesTest = [];
                app.data_dataTestVal = [];
                app.data_labelsTestVal = [];
                app.data_fileNamesTestVal = [];
            end
        end

        function onDatasetChanged(app)
            %UPDATEDATASPECIFICUIELEMENTS Updates ui elements which change
            %if the dataset changes/is split differently
            
            % Update file dropdowns
            if ~isempty(app.data_fileNamesTrain)
                app.DP_SelectedFileTrainDataDropDown.Items = app.data_fileNamesTrain;
            end

            if ~isempty(app.data_fileNamesTest)
                app.DP_SelectedFileTestDataDropDown.Items = app.data_fileNamesTest;
            end

            if ~isempty(app.data_fileNamesTestVal)
                app.DP_SelectedFileTestValDataDropDown.Items = app.data_fileNamesTestVal;
            else
                app.DP_SelectedFileTestValDataDropDown.Items = {};
            end

            if ~isempty(app.data_fileNamesTestSwitch)
                app.DS_SelectedFileTestSwitchDataDropDown.Items = app.data_fileNamesTestSwitch;
            else
                app.DS_SelectedFileTestSwitchDataDropDown.Items = {};
            end
            
            if ~isempty(app.data_fileNamesTest)
                app.TD_FileSelectionFileDropDown.Items = app.data_fileNamesTest;
            else
                app.TD_FileSelectionFileDropDown.Items = {};
            end
            
            % Update dataset plots
            if ~isempty(app.data_dataTrain)
                app.updatePreviewData("train");
            end

            if ~isempty(app.data_dataTest)
                app.updatePreviewData("test");
            end

            if ~isempty(app.data_dataTestVal)
                app.DP_SelectedChannelTestValDatalDropDown.Enable = "on";
                app.DP_SelectedFileTestValDataDropDown.Enable = "on";

                app.updatePreviewData("test-val");
            else
                app.DP_SelectedChannelTestValDatalDropDown.Enable = "off";
                app.DP_SelectedFileTestValDataDropDown.Enable = "off";

                cla(app.DP_TestValDataPreviewUIAxes);
            end
            
            % Update dynamic switch dataset plots
            if ~isempty(app.data_dataTestSwitch)
                app.DynamicSwitchPanel.Enable = "on";

                app.updatePreviewData("test-switch");
            else
                app.DynamicSwitchPanel.Enable = "off";

                cla(app.DS_TestSwitchDataPreviewUIAxes);
            end

            % Update storage and panel
            app.updateStorage();
            app.updateTrainingAndDetectionPanel();
        end

        function updateDataAugmentationSlider(app)
            %UPDATEDATAAUGMENTATIONSLIDER Changes data augmentation slider
            %according to selected agumentation mode

            mode = app.DP_AugmentationModeDropDown.Value;

            switch mode
                case "none"
                    app.DP_AugmentationIntensitySlider.Visible="off";
                    app.DP_AugmentationIntensityLabel.Visible="off";
                    app.DP_AugmentedTrainingCheckBock.Visible = "off";
                case "white_noise"
                    app.DP_AugmentationIntensitySlider.Limits = [0, 1];
                    app.DP_AugmentationIntensityLabel.Text = "Standard deviation";
                    app.DP_AugmentationIntensitySlider.Visible="on";
                    app.DP_AugmentationIntensityLabel.Visible="on";
                    app.DP_AugmentedTrainingCheckBock.Visible = "on";
                case "random_walk"
                    app.DP_AugmentationIntensitySlider.Limits = [0, 1];
                    app.DP_AugmentationIntensityLabel.Text = "Standard deviation";
                    app.DP_AugmentationIntensitySlider.Visible="on";
                    app.DP_AugmentationIntensityLabel.Visible="on";
                    app.DP_AugmentedTrainingCheckBock.Visible = "on";
                case "global_shift"
                    app.DP_AugmentationIntensitySlider.Limits = [-100, 100];
                    app.DP_AugmentationIntensityLabel.Text = "Shift in % of the max value";
                    app.DP_AugmentationIntensitySlider.Visible="on";
                    app.DP_AugmentationIntensityLabel.Visible="on";
                    app.DP_AugmentedTrainingCheckBock.Visible = "on";
                case "attenuate_extremum"
                    app.DP_AugmentationIntensitySlider.Limits = [0, 100];
                    app.DP_AugmentationIntensityLabel.Text = "Strength of the transformation in %";
                    app.DP_AugmentationIntensitySlider.Visible="on";
                    app.DP_AugmentationIntensityLabel.Visible="on";
                    app.DP_AugmentedTrainingCheckBock.Visible = "on";                    
            end
        end
        
        function showDatasetParameters(app)
            %SHOWDATASETPARAMETERS Initialize channel selection dropdown menus for
            % preview

            app.DP_SelectedChannelTrainDataDropDown.Items = app.data_channelNames;
            app.DP_SelectedChannelTestDataDropDown.Items = app.data_channelNames;
            app.DP_SelectedChannelTestValDatalDropDown.Items = app.data_channelNames;
            app.DS_SelectedChannelTestSwitchDataDropDown.Items = app.data_channelNames;
            
            % Calc statistical data features
            if ~isempty(app.data_dataTrainRaw)
                fullData = [];
                for i = 1:numel(app.data_dataTrainRaw)
                    fullData = [fullData; app.data_dataTrainRaw{i}];
                end
            elseif ~isempty(app.data_dataTestRaw)
                fullData = [];
                for i = 1:numel(app.data_dataTestRaw)
                    fullData = [fullData; app.data_dataTestRaw{i}];
                end
            end

            [~, app.data_mean, app.data_standardDeviation] = zscore(fullData, 0, 1);
            app.data_minimum = min(fullData, [], 1);
            app.data_maximum = max(fullData, [], 1);

            % Show features of data
            app.DP_DatasetAttributesUITable.Data = [app.data_channelNames', ...
                                                   app.data_minimum', ...
                                                   app.data_maximum', ...
                                                   app.data_mean', ...
                                                   app.data_standardDeviation'];
        end

%% Training panel functions
        
        function fitModels(app, modelIDs)
            %FITMODELS Trains the models
            %
            % Arguments: models - the IDs of the models to traing (E.g.TSAD_FC_AE_1)
            
            if app.TD_TrainingPlotsCheckBox.Value
                trainingPlots = "training-progress";
            else
                trainingPlots = "none";
            end
            
            for i = 1:numel(modelIDs)
                modelID = modelIDs{i};

                % Call training function on model object
                app.Models.(modelID).train(app.data_dataTrain, ...
                                                 app.data_labelsTrain, ...
                                                 app.data_dataTestVal, ...
                                                 app.data_labelsTestVal, ...
                                                 trainingPlots, ...
                                                 true);

                % Remove previous detections of models from storage. This
                % does nothing if the model is trained for the first time.
               
                modelLabel = app.Models.(modelID).instanceInfo.label;
                app.resetPreviousDetectionsOf(modelID, modelLabel);
            end
        end

        function updateThresholdsForSelection(app, modelIDs)
            %UPDATETHRESHOLDSFORSELECTION Insted of fitting models, this
            %function just re-computes static thresholds using the
            %anomalous validation set. This only has an effect if an
            %anomalous validation set is used on the Dataset Preparation
            %Panel and if the model is semi-supervised.
            
            for i = 1:numel(modelIDs)
                modelID = modelIDs{i};

                % Compute static thresholds
                app.Models.(modelID).computeStaticThresholds(app.data_dataTestVal, ...
                                                             app.data_labelsTestVal);

                % Remove previous detections of models from storage. This
                % does nothing if the model is trained for the first time.
               
                modelLabel = app.Models.(modelID).instanceInfo.label;
                app.resetPreviousDetectionsOf(modelID, modelLabel);
            end
        end

        function intanceNumber = getModelInstanceNumber(app, modelName)
            %GETMODELINSTANCENUMBER Gets the first free instance number for
            %the model class
            
            % Get all used instance numbers for model class
            usedInstanceNumbers = [];
            existingModels = fieldnames(app.Models);
            for i = 1:numel(existingModels)
                if strcmp(modelName, app.Models.(existingModels{i}).parameters.name)
                    usedInstanceNumbers = [usedInstanceNumbers, app.Models.(existingModels{i}).instanceInfo.instanceNumber];
                end
            end
            
            % Compute first free instance number
            intanceNumber = 0;
            while true
                if ismember(intanceNumber, usedInstanceNumbers)
                    intanceNumber = intanceNumber + 1;
                    continue;
                else
                    break;
                end
            end
        end
        
        function graph = createModelGraphForPreview(~, modelOptions)
            % Creates the model graph of a DNN

            layers = getLayers(modelOptions, {""}, {""});
            a = layers.Connections;
            b = extractBefore(a.Source, "/");
            c = extractBefore(a.Destination, "/");
            for i = 1:size(a, 1)
                if ~isempty(b{i})
                    a.Source{i} = b{i};
                end
                if ~isempty(c{i})
                    a.Destination{i} = c{i};
                end
            end
            EndNodes = [a.Source a.Destination];
            nodeTable = table(EndNodes);
            graph = digraph(nodeTable);
        end

        function showModelPreview(app, modelOptions)
            % Shows the parameters of a selected model

            app.T_NoModelLoadedforPreviewLabel.Visible = "off";

            cla(app.T_PreviewUIAxes);

            app.T_PreviewGeneralInformationUITable.Data = [];
            app.T_PreviewHyperparametersUITable.Data = [];

            newData = [];

            newData = [newData; {"Name:", modelOptions.name}];
            newData = [newData; {"Type:", modelOptions.type}];
            newData = [newData; {"Learning Type:", modelOptions.learningType}];

            if isfield(modelOptions, "modelType")
                newData = [newData; {"Model Type:", modelOptions.modelType}];
            end

            if isfield(modelOptions, "dimensionality")
                newData = [newData; {"Dimensionality:", modelOptions.dimensionality}];
            end

            if isfield(modelOptions, "useSlidingWindow")
                if modelOptions.useSlidingWindow
                    newData = [newData; {"Use sliding Window:", "yes"}];
                else
                    newData = [newData; {"Use sliding Window:", "no"}];
                end
            end

            app.T_PreviewGeneralInformationUITable.Data = table(newData);

            if isfield(modelOptions, "hyperparameters")
                hyperparametersData = [];
                paramNames = fieldnames(modelOptions.hyperparameters);
                for i = 1:numel(paramNames)
                    hyperparametersData = [hyperparametersData; {sprintf("%s:", paramNames{i}), string(modelOptions.hyperparameters.(paramNames{i}))}];
                end
                app.T_PreviewHyperparametersUITable.Data = table(hyperparametersData);
            end


            if strcmp(modelOptions.type, "deep-learning")
                graph = app.createModelGraphForPreview(modelOptions);
                plot(app.T_PreviewUIAxes, graph);
                app.T_PreviewUIAxes.Title.String = "Network Architecture";
            else
                app.T_PreviewUIAxes.Title.String = "";
            end

            app.T_PreviewGeneralInformationUITable.Visible = "on";
            app.T_PreviewHyperparametersUITable.Visible = "on";
            app.T_PreviewShowingLabel.Text = sprintf("Showing: %s", modelOptions.label);
            app.T_PreviewShowingLabel.Visible = "on";
        end

%% Detection panel functions

        function runDetection(app)
            %RUNDETECTION Runs the detection using the models (= main testing
            %function)

            getWindowComputationTime = app.TD_GetWindowComputationTimeCheckBox.Value;
            updateThresholdsOnly = app.TD_UpdateThresholdOnlyCheckBox.Value;
            
            % Get all files to be tested
            if app.TD_FileSelectionButtonGroup.SelectedObject == app.TD_FileSelectionAllFilesButton
                files = app.data_fileNamesTest;
            else
                files = {app.TD_FileSelectionFileDropDown.Value};
            end
            
            % Get all models to be tested
            if app.TD_ModelSelectionButtonGroup.SelectedObject == app.TD_ModelSelectionAllButton
                modelIDs = app.TD_ModelsListBox.ItemsData;
            else
                modelIDs = app.TD_ModelsListBox.Value;
            end

            if isempty(modelIDs)
                return;
            end

            % Handle which models detection is displayed
            shownModelChanged = false;
            for i = 1:numel(modelIDs)
                if strcmp(app.currentDetection_modelID, modelIDs{i})
                    % Disable overwriting of currently displayed model later in
                    % this function
                    shownModelChanged = true;
                    break;
                end
            end
            
            for i = 1:numel(files)
                % get correct index for data and labels
                [~, file_idx] = ismember(files{i}, app.data_fileNamesTest);

                for j = 1:numel(modelIDs)
                    if updateThresholdsOnly
                        % If the model is found in storage, apply the
                        % threshold and store new results
                        if isfield(app.detectionStorage.(files{file_idx}), modelIDs{j})
                            app.applyThresholdAndStore(modelIDs{j}, ...
                                                       app.detectionStorage.(files{file_idx}).(modelIDs{j}).anomalyScores, ...
                                                       app.detectionStorage.(files{file_idx}).(modelIDs{j}).timeSeries, ...
                                                       app.detectionStorage.(files{file_idx}).(modelIDs{j}).labels, ...
                                                       app.detectionStorage.(files{file_idx}).(modelIDs{j}).windowComputationTime, ...
                                                       files{file_idx}, ...
                                                       false);
                            
                            % Handle which model to display in plots
                            % section
                            if ~shownModelChanged
                                app.currentDetection_modelID = modelIDs{j};
                                shownModelChanged = true;
                            end
                        end
                    else
                        % Detection is only possible for models which are
                        % trained (unsupervised models also need to set
                        % this variable to true. This happens automatically
                        % in the TSADModel class)
                        if ~(app.Models.(modelIDs{j}).isTrained)
                            continue;
                        end
                        
                        % Run detection
                        [anomalyScores, timeSeriesTest, labelsTest, windowComputationTime] = app.Models.(modelIDs{j}).detect(app.data_dataTest(file_idx), ...
                                                                                                                               app.data_labelsTest(file_idx), ...
                                                                                                                               getWindowComputationTime);
                        % Apply thresholds and store results                                                                                         
                        app.applyThresholdAndStore(modelIDs{j}, anomalyScores, timeSeriesTest, labelsTest, windowComputationTime, files{i}, true);
                        
                        % Handle which model to display in plots section
                        if ~shownModelChanged
                            app.currentDetection_modelID = modelIDs{j};
                            shownModelChanged = true;
                        end
                    end
                end
            end
        end

        function applyThresholdAndStore(app, modelID, anomalyScores, timeSeries, labels, windowComputationTime, fileName, isInitialDetection)
            %APPLYTHRESHOLDANDSTORE Updates the detection by applying the
            %slected threshold and storing the scores
            
            % Apply threshold if required
            if strcmp(app.Models.(modelID).parameters.outputType, "anomaly_scores")
                selectedThreshold = app.TD_ThresholdDropDown.Value;

                % Check if threshold in storage to avoid re-calculating the same threshold
                if ~isInitialDetection ...
                        && isfield(app.detectionStorage.(fileName), modelID) ...
                        && isfield(app.detectionStorage.(fileName).(modelID), "thresholds") ...
                        && isfield(app.detectionStorage.(fileName).(modelID).thresholds, selectedThreshold)
                    storedThreshold = app.detectionStorage.(fileName).(modelID).thresholds.(selectedThreshold);
                    storedThresholdFound = true;
                else
                    storedThreshold = [];
                    storedThresholdFound = false;
                end
                
                % Apply threshold. If storedThreshold is empty the
                % threshold will be computed inside this function or taken
                % from the model itself
                [predictedLabels, threshold] = app.Models.(modelID).applyThreshold(anomalyScores, labels, selectedThreshold, app.settings_dynamicThreshold, storedThreshold);
            else
                predictedLabels = anomalyScores;
                threshold = [];
            end
            
            % Store scores
            scores = computeMetrics(anomalyScores, predictedLabels, labels);
            scores = [windowComputationTime; scores];
            app.updateFullScores(scores, app.Models.(modelID).instanceInfo.label, fileName);
            
            % Save anomaly scores, time series and labels for new detections and reset stored thresholds
            if isInitialDetection
                app.detectionStorage.(fileName).(modelID).timeSeries = timeSeries;
                app.detectionStorage.(fileName).(modelID).anomalyScores = anomalyScores;
                app.detectionStorage.(fileName).(modelID).labels = labels;
                app.detectionStorage.(fileName).(modelID).windowComputationTime = windowComputationTime;

                if strcmp(app.Models.(modelID).parameters.outputType, "anomaly_scores")
                    app.removeStoredThresholdsOf(modelID, fileName);
                end
            end
            
            % Store current threshold and predicted Labels
            app.detectionStorage.(fileName).(modelID).threshold = threshold;
            app.detectionStorage.(fileName).(modelID).predictedLabels = predictedLabels;
            
            % Save threshold if not already stored
            if strcmp(app.Models.(modelID).parameters.outputType, "anomaly_scores") && ~storedThresholdFound
                app.addToThresholdStorage(selectedThreshold, threshold, modelID, fileName);
            end
        end

        function updateDetectionPlots(app)
            %UPDATEDETECTIONPLOTS Updates plots on training and detection
            %panel showning to show the detection for the correct model, file and threshold
            
            % Clear plots first and later fill with new conent
            app.clearDetectionPlots();

            % If currentDetection_modelID is empty, select top ranking model
            if isempty(app.currentDetection_modelID)
                if isempty(app.TD_ModelsListBox.ItemsData)
                    return;
                end

                app.currentDetection_modelID = app.TD_ModelsListBox.ItemsData{1};
            end

            fileName = app.TD_FileSelectionFileDropDown.Value;

            if ~isfield(app.detectionStorage, fileName)
                return;
            end

            if ~isfield(app.detectionStorage.(fileName), app.currentDetection_modelID)
                return;
            end

            if ~isfield(app.Models, app.currentDetection_modelID)
                return;
            end

            label = app.Models.(app.currentDetection_modelID).instanceInfo.label;
            app.TD_DetectionShownForLabel.Text = sprintf("Detection shown for %s", label);
            
            % Get time series, anomaly scores and labels for plotting
            timeSeriesTest = app.detectionStorage.(fileName).(app.currentDetection_modelID).timeSeries;
            anomalyScores = app.detectionStorage.(fileName).(app.currentDetection_modelID).anomalyScores;
            labels = app.detectionStorage.(fileName).(app.currentDetection_modelID).labels;
            scores = app.fullScores.(fileName).(label);
            predictedLabels = app.detectionStorage.(fileName).(app.currentDetection_modelID).predictedLabels;
            threshold = app.detectionStorage.(fileName).(app.currentDetection_modelID).threshold;

            % Plot time series
            if iscell(timeSeriesTest)
                timeSeriesTest = cell2mat(timeSeriesTest);
            end
            plot(app.TD_PlotsDataUIAxes, timeSeriesTest(:, 1));

            % Plot anomaly scores / binary detection for models which
            % output labels
            outputsAnomalyScores = strcmp(app.Models.(app.currentDetection_modelID).parameters.outputType, "anomaly_scores");
            
            if outputsAnomalyScores
                app.TD_PlotsAnomalyScoresUIAxes.Title.String  = "Anomaly Scores";
                plot(app.TD_PlotsAnomalyScoresUIAxes, anomalyScores);
            else
                app.TD_PlotsAnomalyScoresUIAxes.Title.String  = "Predicted Labels";
                plot(app.TD_PlotsAnomalyScoresUIAxes, predictedLabels);
            end
            
            % Plot transparent boxes for true and found anomalies
            predictedIndices = getAnomalyIndices(predictedLabels);
            trueIndices = getAnomalyIndices(labels);
            
            % Get limits
            YLims = get(app.TD_PlotsDataUIAxes, "YLim");
            predictedLims = [];
            trueLims = [];
            for i = 1:size(predictedIndices, 2)
                predictedLims(1, i) = YLims(1);
                predictedLims(2, i) = YLims(1);
                predictedLims(3, i) = YLims(2);
                predictedLims(4, i) = YLims(2);
            end
            for i = 1:size(trueIndices, 2)
                trueLims(1, i) = YLims(1);
                trueLims(2, i) = YLims(1);
                trueLims(3, i) = YLims(2);
                trueLims(4, i) = YLims(2);
            end
            
            if ~isempty(predictedIndices) && ~isempty(predictedLims)
                patch(app.TD_PlotsDataUIAxes, predictedIndices, predictedLims, "red", EdgeColor="none", FaceAlpha=0.4);
            end
            if ~isempty(trueIndices) && ~isempty(trueLims)
                patch(app.TD_PlotsDataUIAxes, trueIndices, trueLims, "green", EdgeColor="none", FaceAlpha=0.3);
            end

            % Plot threshold
            if outputsAnomalyScores
                if numel(threshold) > 1
                    windowSize = max(1, floor(size(anomalyScores, 1) * (app.settings_dynamicThreshold.windowSize / 100)));
                    threshX = [];
                    threshY = [];
                    for i = 1:numel(threshold)
                        threshX(i, :) = ((i - 1) * windowSize + 1):(windowSize * i);
                        threshY(i, :) = ones(1, windowSize) * threshold(i);
                    end

                    hold(app.TD_PlotsAnomalyScoresUIAxes, "on")
                    plot(app.TD_PlotsAnomalyScoresUIAxes, threshX', threshY', Color="red")
                    hold(app.TD_PlotsAnomalyScoresUIAxes, "off")
                else
                    yline(app.TD_PlotsAnomalyScoresUIAxes, threshold, "r");
                end
            end
            
            % Limits
            set(app.TD_PlotsAnomalyScoresUIAxes, "XLim", [0 size(anomalyScores, 1)]);
            set(app.TD_PlotsDataUIAxes, "XLim", [0 size(anomalyScores, 1)]);
            
            % Legends
            try
                legend(app.TD_PlotsDataUIAxes, "Data", "Detected Anomalies", "True Anomalies")
            catch
                legend(app.TD_PlotsDataUIAxes, "Data", "Detected Anomalies")
            end            
            
            % Table of scores
            app.TD_PlotsScoresUITable.Data = table(METRIC_NAMES_WITH_COMP_TIME, scores);
        end

        function clearDetectionPlots(app)
            %CLEARDETECTIONPLOTS Clears plots on training and detection
            %panel

            app.TD_DetectionShownForLabel.Text = "Detection Shown for: None";
            app.TD_PlotsScoresUITable.Data = [];
            cla(app.TD_PlotsDataUIAxes);
            cla(app.TD_PlotsAnomalyScoresUIAxes);
        end
        
        function updateTinyDataPreview(app)
            %UPDATETINYDATAPREVIEW Updates the data plot on the training
            %and detection panel

            selectedFile = app.TD_FileSelectionFileDropDown.Value;
            if isempty(selectedFile)
                cla(app.TD_FileSelectionTinyDataPreviewUIAxes);
                return;
            end
            [~, i] = ismember(selectedFile, app.TD_FileSelectionFileDropDown.Items);
            plot(app.TD_FileSelectionTinyDataPreviewUIAxes, app.data_dataTest{i});
        end
        
        function updateStorage(app)
            %UPDATESTORAGE Updates all detection storage variables. This is
            %the only function that must be called when for example the
            %dataset changes or a model is deleted/added.
            
            if ~app.info_dataLoaded
                return;
            end
            
            oldFiles = fieldnames(app.fullScores);
            currentFiles = app.data_fileNamesTest;
            
            oldModelLabels = app.averageScores.Properties.VariableNames;
            if numel(oldModelLabels) < 2
                oldModelLabels = [];
            else
                oldModelLabels = oldModelLabels(2:end);
            end

            modelIDs = fieldnames(app.Models);
            numModels = numel(modelIDs);
            currentModelIDs = strings(numModels, 1);
            currentModelLabels = strings(numModels, 1);
            for i = 1:numModels
                currentModelLabels(i) = app.Models.(modelIDs{i}).instanceInfo.label;
                currentModelIDs(i) = modelIDs{i};
            end

            % Remove non-relevant files (if any) from old scores, anomalyscore, thresholds
            for i = 1:numel(oldFiles)
                if ~ismember(oldFiles{i}, currentFiles)
                    app.fullScores = rmfield(app.fullScores, oldFiles{i});
                    app.detectionStorage = rmfield(app.detectionStorage, oldFiles{i});
                end
            end

            % Remove deleted models (if any) from scores and detection
            % storage and init missing files
            table_tmp = table(METRIC_NAMES_WITH_COMP_TIME);
            for i = 1:numel(currentFiles)
                if isfield(app.fullScores, currentFiles{i})
                    % Remove all deleted models if any
                    modelLabels = app.fullScores.(currentFiles{i}).Properties.VariableNames;
                    for j = 2:numel(modelLabels)
                        if ~ismember(modelLabels{j}, currentModelLabels)
                            app.fullScores.(currentFiles{i}) = removevars(app.fullScores.(currentFiles{i}), modelLabels{j});
                        end
                    end
                else
                    % Add file to full scores because it didn't exist yet
                    app.fullScores.(currentFiles{i}) = table_tmp;
                    app.fullScores.(currentFiles{i}).Properties.VariableNames = "Metric";
                end

                if isfield(app.detectionStorage, currentFiles{i})
                    % Remove all deleted models if any from detection storage
                    IDs = fieldnames(app.detectionStorage.(currentFiles{i}));
                    for j = 1:numel(IDs)
                        if ~ismember(IDs{j}, currentModelIDs)
                            app.detectionStorage.(currentFiles{i}) = rmfield(app.detectionStorage.(currentFiles{i}), IDs{j});
                        end
                    end
                else
                    % Add file to detection storage because it didn't exist
                    % yet
                    app.detectionStorage.(currentFiles{i}) = struct();
                end
            end

            % Add all current models to scores
            for i = 1:numel(currentModelIDs)
                app.addModelToScores(currentModelIDs{i});
            end
        end

        function updateWindowComputationTimePlot(app)
            %UPDATEWINDOWCOMPUTATIONTIMEPLOT Updates window computation time plot

            if ~app.info_testDataLoaded
                return;
            end
            
            % Get metric names
            checkedNodes = app.TP_WindowComputationTimeMetricsTree.CheckedNodes;
            numNodes = numel(checkedNodes);
            selectedMetrics = strings(numNodes, 1);
            for i = 1:numNodes
                selectedMetrics(i) = checkedNodes(i).Text;
            end
            
            % Remove plots
            cla(app.TD_WindowComputationTimeUIAxes);
            if isempty(selectedMetrics)
                xticks(app.TD_WindowComputationTimeUIAxes, []);
                return;
            end

            metrics_idx = [];
            for i = 1:numel(selectedMetrics)                
                metrics_idx(i) = find(app.averageScores{:, 1} == string(selectedMetrics(i)));
            end

            elapsed = app.averageScores{1, 2:end};
            elapsed_idx = 2:(size(app.averageScores, 2));
            nan_idx = isnan(elapsed);
            elapsed_idx(nan_idx) = [];
            elapsed(nan_idx) = [];

            [elapsed, sort_idx] = sort(elapsed);
            elapsed_idx = elapsed_idx(sort_idx);

            if numel(elapsed) > 1
                for i = 1:numel(elapsed) - 1
                    if elapsed(i + 1) == elapsed(i)
                        elapsed(i + 1) = elapsed(i + 1) + 0.0001;
                    end
                end
            end

            cla(app.TD_WindowComputationTimeUIAxes)

            if ~isempty(selectedMetrics)
                hold(app.TD_WindowComputationTimeUIAxes, "on");
                for i = 1:numel(selectedMetrics)
                    plot(app.TD_WindowComputationTimeUIAxes, elapsed, app.averageScores{metrics_idx(i), elapsed_idx}, ".", MarkerSize=5);
                end
                legend(app.TD_WindowComputationTimeUIAxes, selectedMetrics, Location="northwest");                
                xticks(app.TD_WindowComputationTimeUIAxes, elapsed);
                xticklabels(app.TD_WindowComputationTimeUIAxes, app.averageScores.Properties.VariableNames(elapsed_idx));
            end
        end

        function addToThresholdStorage(app, thresholdName, thresholdValue, modelID, fileName)
            %ADDTOTHRESHOLDSTORAGE Stores already computed thresholds
            
            if ~strcmp(thresholdName, "dynamic") % Don't save dynamic threshold because it can be reconfigured.
                app.detectionStorage.(fileName).(modelID).thresholds.(thresholdName) = thresholdValue;
            end
        end

        function updateFullScores(app, scores, modelLabel, fileName)
            %UPDATEFULLSCORES Updates the scores saved in the full scores

            app.fullScores.(fileName).(modelLabel) = scores;
        end

        function updateAverageScores(app)
            %UPDATEAVERAGESCORES Updates average scores saved in averageScores table
            
            if isempty(app.data_fileNamesTest)
                return;
            end

            fullScoresCell = cell(numel(app.data_fileNamesTest), 1);
            for i = 1:numel(app.data_fileNamesTest)
                fullScoresCell{i} = table2array(app.fullScores.(app.data_fileNamesTest(i))(:, 2:end));
            end
            
            % Copy full scores to average scores to init layout of table
            app.averageScores = app.fullScores.(app.data_fileNamesTest(1));

            % Now fill with average scores
            app.averageScores{:, 2:end} = calcAverageScores(fullScoresCell); 
        end

                
        function clearStorage(app)
            %CLEARSTORAGE Removes all stored scores, anomaly scores and thresholds
            
            tableTmp = table(METRIC_NAMES_WITH_COMP_TIME);
            for i = 1:numel(app.data_fileNamesTest)
                % Replace table of scores with default table without models
                app.fullScores.(app.data_fileNamesTest(i)) = tableTmp;
                app.fullScores.(app.data_fileNamesTest(i)).Properties.VariableNames = "Metric";

                % Replace detection storage with empty struct
                app.detectionStorage.(app.data_fileNamesTest(i)) = struct();
            end
        end

        function addModelToScores(app, modelID)
            %ADDMODELTOSCORES Adds a new model to the scores

            modelLabel = app.Models.(modelID).instanceInfo.label;
            
            if isempty(app.data_fileNamesTest)
                return;
            end
            
            for i = 1:numel(app.data_fileNamesTest)
                % Add model if not alreay available
                if ~ismember(modelLabel, app.fullScores.(app.data_fileNamesTest(i)).Properties.VariableNames)
                    app.fullScores.(app.data_fileNamesTest(i)).(modelLabel) = [NaN; NaN; NaN; NaN; ...
                                                                               NaN; NaN; NaN; NaN; ...
                                                                               NaN; NaN; NaN; NaN; ...
                                                                               NaN; NaN; NaN; NaN];

                end
            end
            
        end

        function resetStorageOf(app, modelID, modelLabel)
            %RESETSTORAGEOF Resets all stored scores of a model to NaN amd
            %removes it from detection storage
            
            if isempty(app.data_fileNamesTest)
                return;
            end

            for i = 1:numel(app.data_fileNamesTest)
                if ismember(modelLabel, app.fullScores.(app.data_fileNamesTest(i)).Properties.VariableNames)
                    app.fullScores.(app.data_fileNamesTest(i)).(modelLabel) = [NaN; NaN; NaN; NaN; ...
                                                                               NaN; NaN; NaN; NaN; ...
                                                                               NaN; NaN; NaN; NaN; ...
                                                                               NaN; NaN; NaN; NaN];
                end

                if isfield(app.detectionStorage.(app.data_fileNamesTest(i)), modelID)
                    app.detectionStorage.(app.data_fileNamesTest(i)) = rmfield(app.detectionStorage.(app.data_fileNamesTest(i)), modelID);
                end
            end
        end

        function removeStoredThresholdsOf(app, modelID, fileName)
            %REMOVESTOREDTHRESHOLDSOF Removes stored thresholds for model and file

            if isfield(app.detectionStorage, fileName) ...
                    && isfield(app.detectionStorage.(fileName), modelID) ...
                    && isfield(app.detectionStorage.(fileName).(modelID), "thresholds") 
                app.detectionStorage.(fileName).(modelID) = rmfield(app.detectionStorage.(fileName).(modelID), "thresholds");
            end
        end
        
        function resetPreviousDetectionsOf(app, modelID, modelLabel)
            %RESETPREVIOUSDETECTIONSOF Resets all previous detections 
            % of a model
            
            app.resetStorageOf(modelID, modelLabel);

            if strcmp(app.currentDetection_modelID, modelID)
                app.clearDetectionPlots();
            end
        end

        function displayModels(app)
            %DISPLAYMODELS Sorts and displays the models on the detection panels
            % The sorting is done according to the average score (as selected by the sort-by dropdown) of the
            % model

            app.TD_ModelsListBox.Items = {};
            app.TD_ModelsListBox.ItemsData = [];

            % Get all model IDs
            modelIDs = fieldnames(app.Models);
            numModels = numel(modelIDs);

            if numModels == 0
                return;
            end
            
            % If no scores are available yet or no test data is loaded
            if isempty(app.averageScores)
                items = strings(numModels, 1);
                itemsData = cell(numModels, 1);
    
                for i = 1:numModels
                    modelID= modelIDs{i};
                    modelLabel = app.Models.(modelID).instanceInfo.label;

                    items(i) = modelLabel;
                    itemsData{i} = modelID;
                end

                app.TD_ModelsListBox.Items = items;
                app.TD_ModelsListBox.ItemsData = itemsData;
                return;
            end


            % Metric for sorting models
            sortBy = app.TD_SortByDropDown.Value;
            [~, scoreIdx] = ismember(sortBy, app.averageScores{:, 1});

            items = strings(numModels, 1);
            itemsData = cell(numModels, 1);
            scoreValues = zeros(numModels, 1);
            isTrainedValues = zeros(numModels, 1);

            for i = 1:numModels
                modelID= modelIDs{i};
                modelLabel = app.Models.(modelID).instanceInfo.label;
                
                % Get scores for model
                modelScores = app.averageScores.(modelLabel);

                scoreValues(i) = modelScores(scoreIdx);
                isTrainedValues(i) = app.Models.(modelID).isTrained;

                items(i) = modelLabel;
                itemsData{i} = modelID;
            end
            
            % Sort models
            % Turn NaN into -Inf to sort them to the end
            scoreValues(isnan(scoreValues)) = -Inf;
            [~, sortedScoreIdx] = sort(scoreValues, "descend");

            sortedItems = items(sortedScoreIdx);
            sortedItemsData = itemsData(sortedScoreIdx);
            sortedScoreValues = scoreValues(sortedScoreIdx);
            sortedIsTrainedValues = isTrainedValues(sortedScoreIdx);
            
            % Add model rank and score to label
            for i = 1:numel(sortedItems)
                if sortedScoreValues(i) ~= -Inf
                    sortedItems(i) = sprintf("%d. (Score: %.2f)   %s", i, sortedScoreValues(i), sortedItems(i));
                else
                    sortedItems(i) = sprintf("%d. (Score: NaN)   %s", i, sortedItems(i));
                end

                if sortedIsTrainedValues(i)
                    sortedItems(i) = sprintf("☑  %s", sortedItems(i));
                else
                    sortedItems(i) = sprintf("☐  %s", sortedItems(i));
                end
            end

            app.TD_ModelsListBox.Items = sortedItems;
            app.TD_ModelsListBox.ItemsData = sortedItemsData;
        end

        function displayScores(app)
            %DISPLAYSCORES Shows scores for current data file and trained models
            
            if ~app.info_testDataLoaded
                return;
            end

            shownScores = app.fullScores.(app.TD_FileSelectionFileDropDown.Value);
            app.TD_ScoresUITable.Data = {};
            app.TD_AverageScoresUITable.Data = {};
            if ~isempty(shownScores)
                app.TD_ScoresUITable.Data = shownScores;
                app.TD_AverageScoresUITable.Data = app.averageScores;
                app.TD_ScoresUITable.ColumnName = shownScores.Properties.VariableNames;
                app.TD_AverageScoresUITable.ColumnName = app.averageScores.Properties.VariableNames;
                
                if size(shownScores, 2) > 1
                    removeStyle(app.TD_ScoresUITable);
                    removeStyle(app.TD_AverageScoresUITable);

                    % Add blue background for comp time row
                    styleBlue = uistyle;
                    styleBlue.BackgroundColor = "#97b0e7";
                    addStyle(app.TD_ScoresUITable, styleBlue, row=1);
                    addStyle(app.TD_AverageScoresUITable, styleBlue, row=1);

                    % Highlight maxima
                    app.highlightTableMaxima(app.TD_ScoresUITable, 1);
                    app.highlightTableMaxima(app.TD_AverageScoresUITable, 1);
                end
            end
        end
     
        % function path = saveModelForSim(app, Mdl, modelID)
        %     % Saves the selected model for the simulink detection
        % 
        %     f = figure("Renderer", "painters", "Position", [-100 -100 0 0]); % Dummy figure for keeping app in foreground after uigetfile/uiputfile
        %     [file, path] = uiputfile("*.mat", "Save Model for Simulink Detection", sprintf("%s.mat", modelID));
        %     delete(f);
        %     if isequal(path, 0)
        %         uialert(app.TSADPlatformUIFigure, "No valid path selected.", ...
        %                 "Export Error", Icon="error");
        %         return;
        %     end
        % 
        %     fileName = fullfile(path, file);
        %     assignin("base", "Mdl", Mdl);
        %     save(fileName, "Mdl");
        % 
        %     path = fileName;
        % end

        function saveScoresTable(app, UITable, dlgTitle, fileName)
            table = UITable.Data;
            table.Properties.VariableNames = UITable.ColumnName;

            variableNames = table.Properties.VariableNames;
            if numel(variableNames) < 2
                return;
            end


            f = figure("Renderer", "painters", "Position", [-100 -100 0 0]); % Dummy figure for keeping app in foreground after uigetfile/uiputfile
            [file, path] = uiputfile("*.csv", dlgTitle, sprintf("%s_%s.csv", fileName, datetime("now", 'Format','MMMM_d_yyyy_HH_mm_ss')));
            delete(f);
            if isequal(path, 0)
                uialert(app.TSADPlatformUIFigure, "No valid path selected.", ...
                        "Export Error", Icon="error");
                return;
            end

            exportFileName = fullfile(path, file);
            
            try
                writetable(table, exportFileName);
            catch ME
                uialert(app.TSADPlatformUIFigure, ME.message, "Export Error", Icon="error");
                return;
            end

            uialert(app.TSADPlatformUIFigure, sprintf("Saved table to %s", exportFileName), ...
                    "Table Saved Successfully", Icon="success");
        end

        function loadModelConfigFile(app)
            %LOADMODELCONFIGFILE Loads models from configuration file

            f = figure("Renderer", "painters", "Position", [-100 -100 0 0]); % Dummy figure for keeping app in foreground after uigetfile/uiputfile
            [file, path] = uigetfile("*.json", "Select Config File", pwd);
            delete(f);
            
            if ~isequal(file, 0) && ~isequal(path, 0)
                filePath = fullfile(path, file);

                JSONData = loadJsonFromFile(filePath);
                
                for i = 1:numel(JSONData.models)
                    model = JSONData.models(i);
                    app.instantiateModel(model.parameters);
                end                
            end
        end

        function loadModelConfigDefault(app)
            %LOADMODELCONFIGDEFAULT Loads default model config from file

            JSONData = loadJsonFromFile("TSADConfig_models.json");

            models = fieldnames(JSONData);
            
            for i = 1:numel(models)
                model = JSONData.(models{i});
                parameters = struct();

                if isfield(model.parameters, "static")
                    fields = fieldnames(model.parameters.static);
                    for j = 1:numel(fields)
                        parameters.(fields{j}) = model.parameters.static.(fields{j});
                    end
                end

                if isfield(model.parameters, "configurable")
                    fields = fieldnames(model.parameters.configurable);
                    for j = 1:numel(fields)
                        parameters.(fields{j}) = model.parameters.configurable.(fields{j}).initialValue;
                    end
                end

                app.instantiateModel(parameters);
            end
        end

        function exportModelConfig(app)
            %EXPORTMODELCONFIG Exports configs of models

            modelIDs = fieldnames(app.Models);

            JSONData = struct();
            JSONData.models = [];

            for i = 1:numel(modelIDs)
                model.parameters = app.Models.(modelIDs{i}).parameters;
                JSONData.models = [JSONData.models, model];
            end

            app.exportStructToJSON(JSONData);
        end

        function exportStructToJSON(app, structData)
            %EXPORTSTRUCTTOJSON Exports a struct to a JSON file

            f = figure("Renderer", "painters", "Position", [-100 -100 0 0]); % Dummy figure for keeping app in foreground after uigetfile/uiputfile
            [file, path] = uiputfile("*.json", "Select Folder", "myConfig.json");
            delete(f);
            if isequal(path, 0)
                uialert(app.TSADPlatformUIFigure, "No valid path selected.", ...
                        "Export Error", Icon="error");
                return;
            end

            fileName = fullfile(path, file);
            fid = fopen(fileName, "w+");
            encodedJSON = jsonencode(structData, PrettyPrint=true);
            fprintf(fid, encodedJSON);
        end

        function status = exportModels(app)
            %EXPORTMODELS Exports models (not only config but entire TSAD
            %model struct)

            if isempty(fieldnames(app.Models))
                status = "no models found";
                return;
            end

            f = figure("Renderer", "painters", "Position", [-100 -100 0 0]); % Dummy figure for keeping app in foreground after uigetfile/uiputfile
            [file, path] = uiputfile("*.mat", "Export Models", "models.mat");
            delete(f);

            if isequal(path, 0)
                status = "invalid path selected";
                return;
            end
            
            Models = app.Models;
            
            exportFileName = fullfile(path, file);
            assignin("base", "Models", Models);
            save(exportFileName, "Models");

            status = "success";
        end
        
        function errorOccured = importModels(app)
            %IMPORTMODELS Import models (not only config but entire TSAD
            %model struct)

            f = figure("Renderer", "painters", "Position", [-100 -100 0 0]); % Dummy figure for keeping app in foreground after uigetfile/uiputfile
            [file, path] = uigetfile("*.mat", "Select a trained Models File", pwd);
            delete(f);

            if ~isequal(file, 0) && ~isequal(path, 0)
                filePath = fullfile(path, file);
                
                try
                    app.Models = load(filePath).Models;
                catch
                    errorOccured = true;
                    return;
                end
            end

            errorOccured = false;
        end
        
        function updateTrainingAndDetectionPanel(app)
            %UPDATETRAININGANDDETECTIONPANEL Update listbox items
            app.updateAverageScores();
            app.displayScores();
            app.displayModels();
            app.updateDetectionPlots();
            app.updateWindowComputationTimePlot();
            app.updateTinyDataPreview();
        end

        function updateDetection(app, modelID)
            %UPDATEDETECTION Updates the shown detection plots. Must be called whenever
            %The selected model, threshold or file is changed

            app.currentDetection_modelID = modelID;
            
            % Show nothing if modelID is empty
            if isempty(modelID)
                app.clearDetectionPlots();
                return;
            end
            
            fileName = app.TD_FileSelectionFileDropDown.Value;
            % If no testing data is loaded, no detection is available.
            % Therefore return
            if isempty(fileName)
                return;
            end
            % If the detection wasn't run for this model also return
            if ~isfield(app.detectionStorage.(fileName), modelID)
                return;
            end            

            timeSeries = app.detectionStorage.(fileName).(modelID).timeSeries;
            anomalyScores = app.detectionStorage.(fileName).(modelID).anomalyScores;
            labels = app.detectionStorage.(fileName).(modelID).labels;
            windowComputationTime = app.detectionStorage.(fileName).(modelID).windowComputationTime;
            
            % Update applied threshold and store new detection results
            app.applyThresholdAndStore(modelID, anomalyScores, timeSeries, labels, windowComputationTime, fileName, false);
        end

        function updateDynamicThresholdSettings(app)
            %UPDATEDYNAMICTHRESHOLDSETTINGS Update dynamic threshold settings
            app.settings_dynamicThreshold.zRange = app.TD_ZRangeSlider.Value;
            app.settings_dynamicThreshold.windowSize = app.TD_WindowSizeSlider.Value;
            app.settings_dynamicThreshold.anomalyPadding = app.TD_AnomalyPaddingSlider.Value;
        end

        function instantiateModel(app, parameters)
            %INSTANTIATEMODEL Creates an instance of the model using the
            %specified parameters

            name = parameters.name;
            className = parameters.className;
            
            % Construct instanceInfo
            instanceNumber = app.getModelInstanceNumber(name);
            instanceInfo.instanceNumber = instanceNumber;
            
            if instanceNumber > 0
                instanceInfo.label = sprintf("%s (%d)", name, instanceNumber);
                instanceInfo.id = sprintf("%s_%d", className, instanceNumber);
            else
                instanceInfo.label = name;
                instanceInfo.id = className;
            end
            
            % Call constructor of class for model in "models" package (See
            % folder "src/models/+models")
            app.Models.(instanceInfo.id) = models.(className)(parameters, instanceInfo);
        end
    end

    methods (Access = public)
%% Settings update functions called by settings popup apps
        
        function bestModels = findBestModels(app, metric)
            [~, metric_idx] = ismember(metric, METRIC_NAMES);
            metric_idx = metric_idx + 1; % Add 1 because fullScores containes the comp time in the first row

            testFiles = fieldnames(app.fullScores);

            for i = 1:numel(testFiles)
                fileScores = app.fullScores.(testFiles{i});
                [~, model_idx] = max(fileScores{metric_idx, 2:end});
                modelLabel = fileScores.Properties.VariableNames(model_idx + 1);
                modelIDs = fieldnames(app.Models);
                for j = 1:numel(modelIDs)
                    labelTmp = app.Models.(modelIDs{j}).instanceInfo.label;
                    if strcmp(labelTmp, modelLabel)
                        modelID = modelIDs{j};
                        break;
                    end
                end
                bestModels.(testFiles{i}).id = modelID;
                bestModels.(testFiles{i}).label = modelLabel;
            end
        end
        

%% Training and Detection panel functions called by popup apps
        
        function addModel(app, parameters)
            %ADDMODEL Adds configured model to platform
            
            % Create instance of model and store in Models variable
            app.instantiateModel(parameters);

            % Update panel
            app.updateStorage();
            app.updateTrainingAndDetectionPanel();
        end

        function updateModel(app, modelID, parameters)
            %UPDATEMODEL Updates model parameters

            if ~isfield(app.Models, modelID)
                return;
            end
            
            % Update parameters
            app.Models.(modelID).setParameters(parameters);

            % Get model label
            modelLabel = app.Models.(modelID).instanceInfo.label;

            % Reset storage and update panel
            app.resetStorageOf(modelID, modelLabel);
            app.updateStorage();
            app.updateTrainingAndDetectionPanel();
        end

        function closeProgressDialog(app)
            %CLOSEPROGRESSDIALOG Closes progressdialog
            close(app.progressDialog);
        end

        function startAutoRun(app, datasetPath, outputPath, models, options)
            %STARTAUTORUN Starts the auto run

            delete(app.popup_AutoRunConfigurator);
            try
                autoRun(datasetPath, outputPath, models, options);

                app.updateStorage();
                app.updateTrainingAndDetectionPanel();
            catch ME
                close(app.progressDialog);
                uialert(app.TSADPlatformUIFigure, ME.message, "Error", Icon="error");
                rethrow(ME);
            end

            close(app.progressDialog);
            uialert(app.TSADPlatformUIFigure, "Successfully finished auto run.", ...
                    "Auto Run Successful", Icon="success");
        end

        function optimizeModels(app, modelIDs, iterations, metric, threshold)
            %OPTIMIZEMODELS Optimizes the models

            delete(app.popup_OptimizationConfigurator);

            % Check if optimization can be done (required data must be
            % loaded)
            if ~app.info_testDataLoaded
                close(app.progressDialog);
                uialert(app.TSADPlatformUIFigure, "No testing data loaded!", ...
                        "Optimization Error", Icon="error");
                return;
            end

            if isempty(modelIDs)
                close(app.progressDialog);
                uialert(app.TSADPlatformUIFigure, "No models found for optimization.", ...
                        "Model Error", Icon="error");
                return;
            end

            % If no training data is loaded but a model requiring prior
            % training was selected
            if isempty(app.data_dataTrain)
                for i = 1:numel(modelIDs)
                    if ~strcmp(app.Models.(modelIDs(i)).modelInfo.learningType, "unsupervised")
                        close(app.progressDialog);
                        uialert(app.TSADPlatformUIFigure, "You are trying to optimize a model but the selected dataset doesn't contain training data", ...
                                "Optimization Error", Icon="error");
                        return;
                    end
                end
            end

            if app.TD_TrainingPlotsCheckBox.Value
                trainingPlots = "training-progress";
            else
                trainingPlots = "none";
            end
            
            try
                for i = 1:numel(modelIDs)
                    modelID = modelIDs{i};
    
                    % Call optimization function on model object
                    app.Models.(modelID).optimize(app.data_dataTrain, ...
                                                         app.data_labelsTrain, ...
                                                         app.data_dataTestVal, ...
                                                         app.data_labelsTestVal, ...
                                                         app.data_dataTest, ...
                                                         app.data_labelsTest, ...
                                                         metric, ...                                                                                    
                                                         threshold, ...
                                                         app.settings_dynamicThreshold, ...
                                                         iterations, ...
                                                         trainingPlots, ...
                                                         app.settings_parallelEnabled);
    
    
                    % Remove previous detections of models from storage. This
                    % does nothing if the model is trained for the first time.
                    modelLabel = app.Models.(modelID).instanceInfo.label;
                    app.resetPreviousDetectionsOf(modelID, modelLabel);
                end
               
                app.updateStorage();
                app.updateTrainingAndDetectionPanel();
            catch ME
                close(app.progressDialog);
                uialert(app.TSADPlatformUIFigure, ME.message, "Error", Icon="error");
                rethrow(ME);
            end

            close(app.progressDialog);
            uialert(app.TSADPlatformUIFigure, "Successfully finished optimization.", ...
                    "Optimization Successful", Icon="success");
        end
        
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Turn off axes for some UIAxes
            app.disableAxesFor(app.TD_FileSelectionTinyDataPreviewUIAxes);            
            
            % Init sort by dropdown
            app.initSortbyDropdown();
            
            % Init threshold dropdown items
            app.initThresholdDropdowns();
            
            % Init dynamic threshold settings
            app.initDynamicThresholdSettings();
            
            % Init computation time metric checkbox nodes
            app.initWindowComputationTimeMetricNodes();
        end

        % Close request function: TSADPlatformUIFigure
        function TSADPlatformUIFigureCloseRequest(app, event)
            answer = uiconfirm(app.TSADPlatformUIFigure, "Close TSAD platform?", "Confirm Close", ...
                Options={'Ok', 'Cancel'}, DefaultOption='Cancel', CancelOption='Cancel');

            if ~strcmp(answer, 'Ok')
                return;
            end
            
            % Close all pupup apps
            if ~isempty(app.popup_ModelSelection)
                app.popup_ModelSelection.delete()
            end
            if ~isempty(app.popup_ModelParametersViewAndUpdate)
                app.popup_ModelParametersViewAndUpdate.delete()
            end
            if ~isempty(app.popup_OptimizationConfigurator)
                app.popup_OptimizationConfigurator.delete()
            end
            if ~isempty(app.popup_AutoRunConfigurator)
                app.popup_AutoRunConfigurator.delete()
            end

            % Close parallel pool
            if ~isempty(gcp("nocreate"))
                delete(gcp("nocreate"));
            end
            
            % Delete app
            app.delete()
        end

        % Button pushed function: DP_BrowseDatasetButton
        function DP_BrowseDatasetButtonPushed(app, event)
            f = figure("Renderer", "painters", "Position", [-100 -100 0 0]); % Dummy figure for keeping app in foreground after uigetfile/uiputfile
            path = uigetdir(pwd);
            delete(f);

            if ~isequal(path, 0)
                app.DP_DatasetFolderEditField.Value = path;
                app.onDatasetEditFieldValueChanged();
            end
        end

        % Value changed function: DP_DatasetFolderEditField
        function DP_DatasetFolderEditFieldValueChanged(app, event)
            app.onDatasetEditFieldValueChanged();
        end

        % Button pushed function: DP_LoadDataButton
        function DP_LoadDataButtonPushed(app, event)
            path = fullfile(app.data_pathName, app.DP_SelectDatasetDropDown.Value);
            
            if isempty(path)
                uialert(app.TSADPlatformUIFigure, "No valid dataset folder selected!", ...
                        "Dataset Error", Icon="error");
                return;
            end

            if ~isfolder(path)
                path = fullfile(pwd, path);
                if ~isfolder(path)
                    uialert(app.TSADPlatformUIFigure, "No valid dataset folder selected!", ...
                            "Dataset Error", Icon="error");
                    return;
                end
            end
            
            app.progressDialog = uiprogressdlg(app.TSADPlatformUIFigure, Title="Loading Dataset ...", ...
                                               Indeterminate="on");
            drawnow;
            
            try
                % Load dataset
                app.loadDataset(path);
                
                % Show statistical dataset features in plot
                app.showDatasetParameters();
    
                % Rreprocess data
                app.preprocessAndSplitDataset(true);
    
                app.onDatasetChanged();
            catch ME
                close(app.progressDialog);
                uialert(app.TSADPlatformUIFigure, ME.message, "Error", Icon="error");
                rethrow(ME);
            end

            close(app.progressDialog);
        end

        % Value changed function: DP_PreprocessingMethodDropDown
        function DP_PreprocessingMethodDropDownValueChanged(app, event)
            if ~app.info_dataLoaded
                return;
            end

            app.progressDialog = uiprogressdlg(app.TSADPlatformUIFigure, Title="Preprocessing Dataset ...", ...
                                               Indeterminate="on");
            drawnow;

            try
                app.preprocessAndSplitDataset(true);
                
                app.onDatasetChanged();
            catch ME
                close(app.progressDialog);
                uialert(app.TSADPlatformUIFigure, ME.message, "Error", Icon="error");
                rethrow(ME);
            end

            close(app.progressDialog);
        end

        % Value changed function: DP_SplitTestSetForDynamicSwitchCheckBox
        function DP_SplitTestSetForDynamicSwitchCheckBoxValueChanged(app, event)
            value = app.DP_SplitTestSetForDynamicSwitchCheckBox.Value;
            app.toggleDynamicSwitchPanel(value);
            
            if value
                app.DP_RatioTestSwitchLabel.Enable = "on";
                app.DP_RatioTestSwitchEditField.Enable = "on";
            else
                app.DP_RatioTestSwitchLabel.Enable = "off";
                app.DP_RatioTestSwitchEditField.Enable = "off";
            end

            if ~app.info_testDataLoaded
                return;
            end

            app.progressDialog = uiprogressdlg(app.TSADPlatformUIFigure, Title="Preprocessing Dataset ...", ...
                                               Indeterminate="on");
            drawnow;
            
            try
                app.preprocessAndSplitDataset(false);
    
                app.onDatasetChanged();
            catch ME
                close(app.progressDialog);
                uialert(app.TSADPlatformUIFigure, ME.message, "Error", Icon="error");
                rethrow(ME);
            end

            close(app.progressDialog);
        end

        % Value changed function: DP_RatioTestSwitchEditField
        function DP_RatioTestSwitchEditFieldValueChanged(app, event)
            if ~app.info_testDataLoaded
                return;
            end
            
            app.progressDialog = uiprogressdlg(app.TSADPlatformUIFigure, Title="Preprocessing Dataset ...", ...
                                               Indeterminate="on");
            drawnow;
            
            try
                app.preprocessAndSplitDataset(false);
    
                app.onDatasetChanged();
            catch ME
                close(app.progressDialog);
                uialert(app.TSADPlatformUIFigure, ME.message, "Error", Icon="error");
                rethrow(ME);
            end

            close(app.progressDialog);
        end

        % Value changed function: DP_SplitTestSetForThresholdingCheckBox
        function DP_SplitTestSetForThresholdingCheckBoxValueChanged(app, event)
            if app.DP_SplitTestSetForThresholdingCheckBox.Value
                app.DP_RatioTestValDataLabel.Enable = "on";
                app.DP_RatioTestValDataEditField.Enable = "on";
            else
                app.DP_RatioTestValDataLabel.Enable = "off";
                app.DP_RatioTestValDataEditField.Enable = "off";
            end

            if ~app.info_testDataLoaded
                return;
            end

            app.progressDialog = uiprogressdlg(app.TSADPlatformUIFigure, Title="Preprocessing Dataset ...", ...
                                               Indeterminate="on");
            drawnow;

            try
                app.preprocessAndSplitDataset(false);
    
                app.onDatasetChanged();
            catch ME
                close(app.progressDialog);
                uialert(app.TSADPlatformUIFigure, ME.message, "Error", Icon="error");
                rethrow(ME);
            end

            close(app.progressDialog);
        end

        % Value changed function: DP_RatioTestValDataEditField
        function DP_RatioTestValDataEditFieldValueChanged(app, event)
            if ~app.info_testDataLoaded
                return;
            end
            
            app.progressDialog = uiprogressdlg(app.TSADPlatformUIFigure, Title="Preprocessing Dataset ...", ...
                                               Indeterminate="on");
            drawnow;
            
            try
                app.preprocessAndSplitDataset(false);
    
                app.onDatasetChanged();
            catch ME
                close(app.progressDialog);
                uialert(app.TSADPlatformUIFigure, ME.message, "Error", Icon="error");
                rethrow(ME);
            end

            close(app.progressDialog);
        end

        % Value changed function: DP_SelectedFileTrainDataDropDown
        function DP_SelectedFileTrainDataDropDownValueChanged2(app, event)
            app.updatePreviewData("train");
        end

        % Value changed function: DP_SelectedChannelTrainDataDropDown
        function DP_SelectedChannelTrainDataDropDownValueChanged2(app, event)
            app.updatePreviewData("train");
        end

        % Value changed function: DP_SelectedFileTestDataDropDown
        function DP_SelectedFileTestDataDropDownValueChanged2(app, event)
            app.updatePreviewData("test");
        end

        % Value changed function: DP_SelectedChannelTestDataDropDown
        function DP_SelectedChannelTestDataDropDownValueChanged2(app, event)
            app.updatePreviewData("test");
        end

        % Value changed function: DP_SelectedFileTestValDataDropDown
        function DP_SelectedFileTestValDataDropDownValueChanged(app, event)
            app.updatePreviewData("test-val");
        end

        % Value changed function: DP_SelectedChannelTestValDatalDropDown
        function DP_SelectedChannelTestValDatalDropDownValueChanged(app, event)
            app.updatePreviewData("test-val");
        end

        % Value changed function: DP_AugmentationIntensitySlider
        function DP_AugmentationIntensitySliderValueChanged(app, event)
            if ~app.info_dataLoaded
                return;
            end

            app.progressDialog = uiprogressdlg(app.TSADPlatformUIFigure, Title="Preprocessing Dataset ...", ...
                                               Indeterminate="on");
            drawnow;
            
            try
                app.preprocessAndSplitDataset(true);
                
                app.onDatasetChanged();
            catch ME
                close(app.progressDialog);
                uialert(app.TSADPlatformUIFigure, ME.message, "Error", Icon="error");
                rethrow(ME);
            end

            close(app.progressDialog);          
        end

        % Value changed function: DP_AugmentationModeDropDown
        function DP_AugmentationModeDropDownValueChanged(app, event)
            %changing the visual of the slider depending on the transformation chosen
            app.updateDataAugmentationSlider();

            if ~app.info_dataLoaded
                return;
            end

            app.progressDialog = uiprogressdlg(app.TSADPlatformUIFigure, Title="Preprocessing Dataset ...", ...
                                               Indeterminate="on");
            drawnow; 
            
            try
                app.preprocessAndSplitDataset(true);
    
                app.onDatasetChanged();
            catch ME
                close(app.progressDialog);
                uialert(app.TSADPlatformUIFigure, ME.message, "Error", Icon="error");
                rethrow(ME);
            end

            close(app.progressDialog);
        end

        % Value changed function: DP_AugmentedTrainingCheckBock
        function DP_AugmentedTrainingCheckBockValueChanged(app, event)
            if ~app.info_trainDataLoaded
                return;
            end
            
            app.progressDialog = uiprogressdlg(app.TSADPlatformUIFigure, Title="Preprocessing Dataset ...", ...
                                               Indeterminate="on");
            drawnow;          
            
            try
                app.preprocessAndSplitDataset(true);
    
                app.onDatasetChanged();
            catch ME
                close(app.progressDialog);
                uialert(app.TSADPlatformUIFigure, ME.message, "Error", Icon="error");
                rethrow(ME);
            end

            close(app.progressDialog);
        end

        % Menu selected function: TD_ViewUpdateModelParametersMenu
        function TD_ViewUpdateModelParametersMenuSelected(app, event)
            modelIDs = app.TD_ModelsListBox.Value;
            if isempty(modelIDs)
                uialert(app.TSADPlatformUIFigure, "No model selected for viewing/updating! Select model first.", ...
                        "Model Viewer Error", Icon="error");F
                return;
            end
            if numel(modelIDs) > 1
                uialert(app.TSADPlatformUIFigure, "Can't view multiple models at once! Please select just a single model.", ...
                        "Model Viewer Error", Icon="error");
                return;
            end

            modelID = modelIDs{1};

            parameters = app.Models.(modelID).parameters;
            app.popup_ModelParametersViewAndUpdate = ModelParametersViewAndUpdate(app, modelID, parameters);
        end

        % Menu selected function: 
        % TD_RecomputeSupervisedStaticThresholdsMenu
        function TD_RecomputeSupervisedStaticThresholdsMenuSelected(app, event)
            modelIDs = app.TD_ModelsListBox.Value;
            if isempty(modelIDs)
                uialert(app.TSADPlatformUIFigure, "No model selected for updating thresholds! Select model first.", ...
                        "Threshold Update Error", Icon="error");
                return;
            end
            
            app.progressDialog = uiprogressdlg(app.TSADPlatformUIFigure, Title="Updating Static Thresholds ...", ...
                                               Indeterminate="on");
            drawnow;
            
            try
                app.updateThresholdsForSelection(modelIDs);
    
                app.updateStorage();
                app.updateTrainingAndDetectionPanel();
            catch ME
                close(app.progressDialog);
                uialert(app.TSADPlatformUIFigure, ME.message, "Error", Icon="error");
                rethrow(ME);
            end

            close(app.progressDialog);
            uialert(app.TSADPlatformUIFigure, "Successfully updated static thresholds.", ...
                    "Update Successful", Icon="success");
        end

        % Button pushed function: TD_ImportModelsButton
        function TD_ImportModelsButtonPushed(app, event)
            app.progressDialog = uiprogressdlg(app.TSADPlatformUIFigure, Title="Loading Models ...", ...
                                               Indeterminate="on");
            drawnow;
            
            try
                importErrorOccured = app.importModels();
    
                app.updateStorage();
                app.updateTrainingAndDetectionPanel;
            catch ME
                close(app.progressDialog);
                uialert(app.TSADPlatformUIFigure, ME.message, "Error", Icon="error");
                rethrow(ME);
            end

            close(app.progressDialog);

            if importErrorOccured
                uialert(app.TSADPlatformUIFigure, "Invalid models file selected.", ...
                        "Import Error", Icon="error");
            end
        end

        % Button pushed function: TD_ExportModelsButton
        function TD_ExportModelsButtonPushed(app, event)
            app.progressDialog = uiprogressdlg(app.TSADPlatformUIFigure, Title="Updating Static Thresholds ...", ...
                                               Indeterminate="on");
            drawnow;
            
            try
                status = app.exportModels();
            catch ME
                close(app.progressDialog);
                uialert(app.TSADPlatformUIFigure, ME.message, "Error", Icon="error");
                rethrow(ME);
            end

            close(app.progressDialog);

            if ~strcmp(status, "success")
                uialert(app.TSADPlatformUIFigure, status, ...
                        "Export Error", Icon="error");
            end
        end

        % Value changed function: TD_SortByDropDown
        function TD_SortByDropDownValueChanged(app, event)
            app.updateTrainingAndDetectionPanel();
        end

        % Value changed function: TD_FileSelectionFileDropDown
        function TD_FileSelectionFileDropDownValueChanged(app, event)
            app.progressDialog = uiprogressdlg(app.TSADPlatformUIFigure, Indeterminate="on");
            drawnow;
            
            try
                app.updateDetection(app.currentDetection_modelID);
                app.updateTrainingAndDetectionPanel();
            catch ME
                close(app.progressDialog);
                uialert(app.TSADPlatformUIFigure, ME.message, "Error", Icon="error");
                rethrow(ME);
            end

            close(app.progressDialog);
        end

        % Value changed function: TD_ThresholdDropDown
        function TD_ThresholdDropDownValueChanged(app, event)
            if strcmp(app.TD_ThresholdDropDown.Value, "dynamic")
                app.TD_DynamicThresholdSettingsPanel.Visible = "on";
            else
                app.TD_DynamicThresholdSettingsPanel.Visible = "off";
            end
            
            app.progressDialog = uiprogressdlg(app.TSADPlatformUIFigure, Indeterminate="on");
            drawnow;
            
            try
                app.updateDetection(app.currentDetection_modelID);
                app.updateTrainingAndDetectionPanel();
            catch ME
                close(app.progressDialog);
                uialert(app.TSADPlatformUIFigure, ME.message, "Error", Icon="error");
                rethrow(ME);
            end

            close(app.progressDialog);
        end

        % Value changed function: TD_AnomalyPaddingSlider
        function TD_AnomalyPaddingSliderValueChanged(app, event)
            app.progressDialog = uiprogressdlg(app.TSADPlatformUIFigure, Indeterminate="on");
            drawnow;
            
            try
                app.updateDynamicThresholdSettings();
                app.updateDetection(app.currentDetection_modelID);
                app.updateTrainingAndDetectionPanel();
            catch ME
                close(app.progressDialog);
                uialert(app.TSADPlatformUIFigure, ME.message, "Error", Icon="error");
                rethrow(ME);
            end

            close(app.progressDialog);
        end

        % Value changed function: TD_WindowSizeSlider
        function TD_WindowSizeSliderValueChanged(app, event)
            app.progressDialog = uiprogressdlg(app.TSADPlatformUIFigure, Indeterminate="on");
            drawnow;
            
            try
                app.updateDynamicThresholdSettings();
                app.updateDetection(app.currentDetection_modelID);
                app.updateTrainingAndDetectionPanel();
            catch ME
                close(app.progressDialog);
                uialert(app.TSADPlatformUIFigure, ME.message, "Error", Icon="error");
                rethrow(ME);
            end

            close(app.progressDialog);
        end

        % Value changed function: TD_ZRangeSlider
        function TD_ZRangeSliderValueChanged(app, event)
            app.progressDialog = uiprogressdlg(app.TSADPlatformUIFigure, Indeterminate="on");
            drawnow;
            
            try
                app.updateDynamicThresholdSettings();
                app.updateDetection(app.currentDetection_modelID);
                app.updateTrainingAndDetectionPanel();
            catch ME
                close(app.progressDialog);
                uialert(app.TSADPlatformUIFigure, ME.message, "Error", Icon="error");
                rethrow(ME);
            end

            close(app.progressDialog);
        end

        % Button pushed function: TD_ScoresExportTableButton
        function TD_ScoresExportTableButtonPushed(app, event)
            app.saveScoresTable(app.TD_ScoresUITable, "Export Scores", "Scores");
        end

        % Button pushed function: TD_AverageScoresExportTableButton
        function TD_AverageScoresExportTableButtonPushed(app, event)
            app.saveScoresTable(app.TD_AverageScoresUITable, "Export Average Scores", "Average Scores");
        end

        % Button pushed function: TD_LoadDefaultButton
        function TD_LoadDefaultButtonPushed(app, event)
            app.progressDialog = uiprogressdlg(app.TSADPlatformUIFigure, Title="Loading Default Config ...", ...
                                               Indeterminate="on");
            drawnow;
            
            try
                app.loadModelConfigDefault();
    
                app.updateStorage();
                app.updateTrainingAndDetectionPanel();
            catch ME
                close(app.progressDialog);
                uialert(app.TSADPlatformUIFigure, ME.message, "Error", Icon="error");
                rethrow(ME);
            end

            close(app.progressDialog);
        end

        % Button pushed function: TD_LoadManuallyButton
        function TD_LoadManuallyButtonPushed(app, event)
            app.popup_ModelSelection = ModelSelection(app);
        end

        % Button pushed function: TD_LoadConfigButton
        function TD_LoadConfigButtonPushed(app, event)
            app.progressDialog = uiprogressdlg(app.TSADPlatformUIFigure, Title="Loading Config ...", ...
                                               Indeterminate="on");
            drawnow;
            
            try
                app.loadModelConfigFile();
    
                app.updateStorage();
                app.updateTrainingAndDetectionPanel();
            catch ME
                close(app.progressDialog);
                uialert(app.TSADPlatformUIFigure, ME.message, "Error", Icon="error");
                rethrow(ME);
            end

            close(app.progressDialog);
        end

        % Button pushed function: TD_ExportConfigButton
        function TD_ExportConfigButtonPushed(app, event)
            app.progressDialog = uiprogressdlg(app.TSADPlatformUIFigure, Title="Exporting Config ...", ...
                                               Indeterminate="on");
            drawnow;
            
            try
                app.exportModelConfig();
            catch ME
                close(app.progressDialog);
                uialert(app.TSADPlatformUIFigure, ME.message, "Error", Icon="error");
                rethrow(ME);
            end

            close(app.progressDialog);
        end

        % Button pushed function: TD_FitAllButton
        function TD_FitAllButtonPushed(app, event)
            if ~app.info_trainDataLoaded
                uialert(app.TSADPlatformUIFigure, "No training data loaded!", ...
                        "Training Error", Icon="error");
                return;
            end

            modelIDs = app.TD_ModelsListBox.ItemsData;
            
            if isempty(modelIDs)
                uialert(app.TSADPlatformUIFigure, "No models loaded!", ...
                        "Training Error", Icon="error");
                return;
            end


            % Open optimization window if enabled
            if app.TD_OptimizeCheckBox.Value
                app.popup_OptimizationConfigurator = OptimizationConfigurator(app, modelIDs);
                app.progressDialog = uiprogressdlg(app.TSADPlatformUIFigure, Title="Optimizing Models ...", ...
                                                   Indeterminate="on");
                return;
            end
            
            % Otherwise just fit models

            app.progressDialog = uiprogressdlg(app.TSADPlatformUIFigure, Title="Training Models ...", ...
                            Indeterminate="on");
            drawnow;
            
            try
                app.fitModels(modelIDs);
    
                app.updateStorage();
                app.updateTrainingAndDetectionPanel();
            catch ME
                close(app.progressDialog);
                uialert(app.TSADPlatformUIFigure, ME.message, "Error", Icon="error");
                rethrow(ME);
            end

            close(app.progressDialog);
            uialert(app.TSADPlatformUIFigure, "Successfully trained models.", ...
                    "Training Successful", Icon="success");
        end

        % Button pushed function: TD_FitSelectionButton
        function TD_FitSelectionButtonPushed(app, event)
            if ~app.info_trainDataLoaded
                uialert(app.TSADPlatformUIFigure, "No training data loaded!", ...
                        "Training Error", Icon="error");
                return;
            end
            
            modelIDs = app.TD_ModelsListBox.Value;
            
            if isempty(modelIDs)
                uialert(app.TSADPlatformUIFigure, "No models selected!", ...
                        "Training Error", Icon="error");
                return;
            end

            % Open optimization window if enabled
            if app.TD_OptimizeCheckBox.Value
                app.popup_OptimizationConfigurator = OptimizationConfigurator(app, modelIDs);
                app.progressDialog = uiprogressdlg(app.TSADPlatformUIFigure, Title="Optimizing Models ...", ...
                                                   Indeterminate="on");
                return;
            end


            % Otherwise just fit models

            app.progressDialog = uiprogressdlg(app.TSADPlatformUIFigure, Title="Training Models ...", ...
                                               Indeterminate="on");
            drawnow;
            
            try
                app.fitModels(modelIDs);
    
                app.updateStorage();
                app.updateTrainingAndDetectionPanel();
            catch ME
                close(app.progressDialog);
                uialert(app.TSADPlatformUIFigure, ME.message, "Error", Icon="error");
                rethrow(ME);
            end

            close(app.progressDialog);
            uialert(app.TSADPlatformUIFigure, "Successfully trained models.", ...
                    "Training Successful", Icon="success");
        end

        % Selection changed function: TD_FileSelectionButtonGroup
        function TD_FileSelectionButtonGroupSelectionChanged(app, event)
            app.progressDialog = uiprogressdlg(app.TSADPlatformUIFigure, Indeterminate="on");
            drawnow;

            try
                if app.TD_FileSelectionButtonGroup.SelectedObject == app.TD_FileSelectionAllFilesButton
                    app.TD_FileSelectionFileLabel.Enable = "off";
                    app.TD_FileSelectionFileDropDown.Enable = "off";
                else
                    app.TD_FileSelectionFileLabel.Enable = "on";
                    app.TD_FileSelectionFileDropDown.Enable = "on";
                    app.updateDetection(app.currentDetection_modelID);
                    app.updateTrainingAndDetectionPanel();
                end
            catch ME
                close(app.progressDialog);
                uialert(app.TSADPlatformUIFigure, ME.message, "Error", Icon="error");
                rethrow(ME);
            end

            close(app.progressDialog);
        end

        % Button pushed function: TD_TrainAndTestAllButton
        function TD_TrainAndTestAllButtonPushed(app, event)
            % dataset path
            datasetPath = app.DP_DatasetFolderEditField.Value;

            % models
            models = app.Models;

            % preprocessing method
            options.preprocessingMethod = app.DP_PreprocessingMethodDropDown.Value;
            
            % Use of anomalous validation set
            if app.DP_SplitTestSetForThresholdingCheckBox.Value
                options.ratioTestVal = app.DP_RatioTestValDataEditField.Value;
            else
                options.ratioTestVal = 0;
            end

            % training plots
            if app.TD_TrainingPlotsCheckBox.Value
                options.trainingPlots = "training-progress";
            else
                options.trainingPlots = "none";
            end
            
            % parallel mode
            options.parallelEnabled = false;

            % computation time
            options.getWindowComputationTime = app.TD_GetWindowComputationTimeCheckBox.Value;
            
            % augmentation
            options.augmentationMode = app.DP_AugmentationModeDropDown.Value;
            options.augmentationIntensity = app.DP_AugmentationIntensitySlider.Value;
            options.augmentedTrainingEnabled = app.DP_AugmentedTrainingCheckBock.Value;

            % dynamic threshold settings
            options.dynamicThresholdSettings = app.settings_dynamicThreshold;

            options.optimizationEnabled = app.TD_OptimizeCheckBox.Value;
    
            app.popup_AutoRunConfigurator = AutoRunConfigurator(app, datasetPath, models, options);
            app.progressDialog = uiprogressdlg(app.TSADPlatformUIFigure, Title="Running Auto Training And Evaluation ...", ...
                                                   Indeterminate="on");
        end

        % Button pushed function: TD_DeleteAllButton
        function TD_DeleteAllButtonPushed(app, event)
            answer = uiconfirm(app.TSADPlatformUIFigure, "Delete Models?", "Confirm Deletion", ...
                Options={'Yes', 'Cancel'}, DefaultOption='Cancel', CancelOption='Cancel');

            if ~strcmp(answer, 'Yes')
                return;
            end
            
            app.progressDialog = uiprogressdlg(app.TSADPlatformUIFigure, Indeterminate="on");
            drawnow; 
            
            try
                app.Models = struct();
                app.updateStorage();
                app.updateTrainingAndDetectionPanel();
            catch ME
                close(app.progressDialog);
                uialert(app.TSADPlatformUIFigure, ME.message, "Error", Icon="error");
                rethrow(ME);
            end

            close(app.progressDialog);
        end

        % Menu selected function: TD_DeleteMenu
        function TD_DeleteMenuSelected(app, event)
            answer = uiconfirm(app.TSADPlatformUIFigure, "Delete Selected Model(s)?", "Confirm Deletion", ...
                Options={'Yes', 'Cancel'}, DefaultOption='Cancel', CancelOption='Cancel');

            if ~strcmp(answer, 'Yes')
                return;
            end
            
            app.progressDialog = uiprogressdlg(app.TSADPlatformUIFigure, Indeterminate="on");
            drawnow; 
            
            try
                modelsToDelete = app.TD_ModelsListBox.Value;
                for i = 1:numel(modelsToDelete)
                    app.Models = rmfield(app.Models, modelsToDelete{i});
                end
                app.updateStorage();
                app.updateTrainingAndDetectionPanel();
            catch ME
                close(app.progressDialog);
                uialert(app.TSADPlatformUIFigure, ME.message, "Error", Icon="error");
                rethrow(ME);
            end

            close(app.progressDialog);
        end

        % Button pushed function: TD_RunDetectionButton
        function TD_RunDetectionButtonPushed(app, event)
            app.progressDialog = uiprogressdlg(app.TSADPlatformUIFigure, Title="Running Detection...", ...
                                               Indeterminate="on");
            drawnow;
            
            try
                app.runDetection();
                app.updateTrainingAndDetectionPanel();
            catch ME
                close(app.progressDialog);
                uialert(app.TSADPlatformUIFigure, ME.message, "Error", Icon="error");
                rethrow(ME);
            end

            close(app.progressDialog);
        end

        % Menu selected function: TD_ShowDetectionMenu
        function TD_ShowDetectionMenuSelected(app, event)
            app.progressDialog = uiprogressdlg(app.TSADPlatformUIFigure, Indeterminate="on");
            drawnow;
            
            try
                model = app.TD_ModelsListBox.Value;
                if numel(model) ~= 1
                    close(app.progressDialog);
                    return;
                end
    
                app.updateDetection(model{1});
                app.updateTrainingAndDetectionPanel();
            catch ME
                close(app.progressDialog);
                uialert(app.TSADPlatformUIFigure, ME.message, "Error", Icon="error");
                rethrow(ME);
            end

            close(app.progressDialog);
        end

        % Menu selected function: TD_ShowNetworkArchitectureMenu
        function TD_ShowNetworkArchitectureMenuSelected(app, event)
            modelIDs = app.TD_ModelsListBox.Value;
            if isempty(modelIDs)
                uialert(app.TSADPlatformUIFigure, "No model selected", ...
                        "Architecture View Error", Icon="error");F
                return;
            end
            if numel(modelIDs) > 1
                uialert(app.TSADPlatformUIFigure, "Can't view multiple model architectures at once! Please select just a single model.", ...
                        "Architecture View Error", Icon="error");
                return;
            end

            if ~app.info_trainDataLoaded
                uialert(app.TSADPlatformUIFigure, "No training data loaded (required for architecture view)!", ...
                        "Architecture View Error", Icon="error");
                return;
            end

            modelID = modelIDs{1};

            app.Models.(modelID).analyzeNeuralNetwork(app.data_dataTrain, app.data_dataTest);            
        end

        % Callback function: TP_WindowComputationTimeMetricsTree
        function TP_WindowComputationTimeMetricsTreeCheckedNodesChanged(app, event)
            app.progressDialog = uiprogressdlg(app.TSADPlatformUIFigure, Indeterminate="on");
            drawnow; 
            
            try
                app.updateTrainingAndDetectionPanel();
            catch ME
                close(app.progressDialog);
                uialert(app.TSADPlatformUIFigure, ME.message, "Error", Icon="error");
                rethrow(ME);
            end

            close(app.progressDialog);
        end

        % Value changed function: DS_SelectedFileTestSwitchDataDropDown
        function DS_SelectedFileTestSwitchDataDropDownValueChanged(app, event)
            app.updatePreviewData("test-switch");
        end

        % Value changed function: DS_SelectedChannelTestSwitchDataDropDown
        function DS_SelectedChannelTestSwitchDataDropDownValueChanged(app, event)
            app.updatePreviewData("test-switch");
        end

        % Button pushed function: DS_FitButton
        function DS_FitButtonPushed(app, event)
            if ~app.DP_SplitTestSetForDynamicSwitchCheckBox.Value
                uialert(app.TSADPlatformUIFigure, "Please check the 'Split Test Set for Dynamic Switch' Checkbox on the Dataset Preparation panel", ...
                        "Data Error", Icon="error");
                return;
            end

            if isempty(fieldnames(app.Models))
                uialert(app.TSADPlatformUIFigure, "No trained models found. Train them first.", ...
                        "Model Error", Icon="error");
                return;
            end

            answer = uiconfirm(app.TSADPlatformUIFigure, "Did you already run the detection for all files?", ...
                                "Detection Run?", Options={'Yes', 'No'}, DefaultOption='No', CancelOption='No');
            if ~strcmp(answer, "Yes")
                return;
            end
            
            app.progressDialog = uiprogressdlg(app.TSADPlatformUIFigure, Title="Training Dynamic Switch ...", ...
                                               Indeterminate="on");
            drawnow;
            
            try
                trainLabels = app.findBestModels(app.TD_SortByDropDown.Value);
                
                fileNamesCell = fieldnames(trainLabels);
                numTrainFiles = numel(fileNamesCell);
                fileNames = strings(numTrainFiles, 1);
                bestModels = strings(numTrainFiles, 1);
                for i = 1:numTrainFiles
                    fileNames(i) = fileNamesCell{i};
                    bestModels(i) = trainLabels.(fileNamesCell{i}).label;
                end
                app.DS_TrainingDataLabelsUITable.Data = [fileNames, bestModels];
    
                app.dynamicSwitch = TSAD_dynamicSwitch();
                app.dynamicSwitch = app.dynamicSwitch.fit(app.Models, app.data_dataTest, trainLabels, fileNames);
            catch ME
                close(app.progressDialog);
                uialert(app.TSADPlatformUIFigure, ME.message, "Error", Icon="error");
                rethrow(ME);
            end

            close(app.progressDialog);
            uialert(app.TSADPlatformUIFigure, "Successfully trained Dynamic Switch.", ...
                    "Training Successful", Icon="success");
        end

        % Button pushed function: DS_EvaluateButton
        function DS_EvaluateButtonPushed(app, event)
            if ~app.DP_SplitTestSetForDynamicSwitchCheckBox.Value
                uialert(app.TSADPlatformUIFigure, "Please check the 'Split Test Set for Dynamic Switch' Checkbox on the Preprocessing panel", ...
                        "Data Error", Icon="error");
                return;
            end       

            if isempty(fieldnames(app.Models))
                uialert(app.TSADPlatformUIFigure, "No trained models found. Train them first on the training panel.", ...
                        "Model Error", Icon="error");
                return;
            end 
            
            if isempty(app.dynamicSwitch)
                uialert(app.TSADPlatformUIFigure, "No classifier trained!", ...
                        "Classifier Error", Icon="error");
                return;
            end

            app.progressDialog = uiprogressdlg(app.TSADPlatformUIFigure, Title="Evaluating Dynamic Switch ...", ...
                                               Indeterminate="on");
            drawnow;
            
            try
                fprintf("\n\n### Evaluating dynamic switch ... ###\n\n");
                
                [finalTable, selectedModels] = app.dynamicSwitch.evaluate(app.data_dataTestSwitch, ...
                                                                             app.data_labelsTestSwitch, ...
                                                                             app.TD_ThresholdDropDown.Value, ...
                                                                             app.settings_dynamicThreshold);
    
                app.DS_ResultsUITable.Data = finalTable;
                app.DS_ResultsUITable.ColumnName = finalTable.Properties.VariableNames;
    
                app.DS_TestingDataPredictionsUITable.Data = [app.data_fileNamesTestSwitch, selectedModels];
    
                removeStyle(app.DS_ResultsUITable);
                app.highlightTableMaxima(app.DS_ResultsUITable);
                
                fprintf("\n\n### Finished evaluating dynamic switch successfully!\n\n");
            catch ME
                close(app.progressDialog);
                uialert(app.TSADPlatformUIFigure, ME.message, "Error", Icon="error");
                rethrow(ME);
            end

            close(app.progressDialog);
            uialert(app.TSADPlatformUIFigure, "Successfully finished evaluation.", ...
                    "Evaluation Successful", Icon="success");
        end

        % Button pushed function: DS_ExportButton
        function DS_ExportButtonPushed(app, event)
            app.saveScoresTable(app.DS_ResultsUITable, "Export Dynamic Switch Results", "Dynamic_Switch_Results");
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.TSADPlatformUIFigure = uifigure('Visible', 'off');
            app.TSADPlatformUIFigure.Name = 'TSAD Platform';
            app.TSADPlatformUIFigure.Icon = fullfile(pathToMLAPP, 'media', 'TSADPlatform_Icon.png');
            app.TSADPlatformUIFigure.CloseRequestFcn = createCallbackFcn(app, @TSADPlatformUIFigureCloseRequest, true);
            app.MainPanel = uitabgroup(app.TSADPlatformUIFigure);
            app.DatasetPreparationTab = uitab(app.MainPanel);
            app.DatasetPreparationTab.Title = '1 Dataset Preparation';
            app.DatasetPreparationPanel = uipanel(app.DatasetPreparationTab);
            app.DP_TrainingDataPreviewUIAxes = uiaxes(app.DatasetPreparationPanel);
            title(app.DP_TrainingDataPreviewUIAxes, 'Training Data')
            xlabel(app.DP_TrainingDataPreviewUIAxes, 'Timestamp')
            ylabel(app.DP_TrainingDataPreviewUIAxes, 'Value')
            zlabel(app.DP_TrainingDataPreviewUIAxes, 'Z')
            app.DP_TestDataPreviewUIAxes = uiaxes(app.DatasetPreparationPanel);
            title(app.DP_TestDataPreviewUIAxes, 'Test Data')
            xlabel(app.DP_TestDataPreviewUIAxes, 'Timestamp')
            ylabel(app.DP_TestDataPreviewUIAxes, 'Value')
            zlabel(app.DP_TestDataPreviewUIAxes, 'Z')
            app.DP_TestValDataPreviewUIAxes = uiaxes(app.DatasetPreparationPanel);
            title(app.DP_TestValDataPreviewUIAxes, 'Data for Computing Thresholds')
            xlabel(app.DP_TestValDataPreviewUIAxes, 'Timestamp')
            ylabel(app.DP_TestValDataPreviewUIAxes, 'Value')
            zlabel(app.DP_TestValDataPreviewUIAxes, 'Z')
            app.SelectedChannelLabel_3 = uilabel(app.DatasetPreparationPanel);
            app.SelectedChannelLabel_3.Text = 'Selected Channel:';
            app.DP_SelectedChannelTrainDataDropDown = uidropdown(app.DatasetPreparationPanel);
            app.DP_SelectedChannelTrainDataDropDown.Items = {};
            app.DP_SelectedChannelTrainDataDropDown.ValueChangedFcn = createCallbackFcn(app, @DP_SelectedChannelTrainDataDropDownValueChanged2, true);
            app.DP_SelectedChannelTrainDataDropDown.Value = {};
            app.SelectedFileLabel_3 = uilabel(app.DatasetPreparationPanel);
            app.SelectedFileLabel_3.Text = 'Selected File:';
            app.DP_SelectedFileTrainDataDropDown = uidropdown(app.DatasetPreparationPanel);
            app.DP_SelectedFileTrainDataDropDown.Items = {};
            app.DP_SelectedFileTrainDataDropDown.ValueChangedFcn = createCallbackFcn(app, @DP_SelectedFileTrainDataDropDownValueChanged2, true);
            app.DP_SelectedFileTrainDataDropDown.Value = {};
            app.SelectedChannelLabel_4 = uilabel(app.DatasetPreparationPanel);
            app.SelectedChannelLabel_4.Text = 'Selected Channel:';
            app.DP_SelectedChannelTestDataDropDown = uidropdown(app.DatasetPreparationPanel);
            app.DP_SelectedChannelTestDataDropDown.Items = {};
            app.DP_SelectedChannelTestDataDropDown.ValueChangedFcn = createCallbackFcn(app, @DP_SelectedChannelTestDataDropDownValueChanged2, true);
            app.DP_SelectedChannelTestDataDropDown.Value = {};
            app.SelectedFileLabel_4 = uilabel(app.DatasetPreparationPanel);
            app.SelectedFileLabel_4.Text = 'Selected File:';
            app.DP_SelectedFileTestDataDropDown = uidropdown(app.DatasetPreparationPanel);
            app.DP_SelectedFileTestDataDropDown.Items = {};
            app.DP_SelectedFileTestDataDropDown.ValueChangedFcn = createCallbackFcn(app, @DP_SelectedFileTestDataDropDownValueChanged2, true);
            app.DP_SelectedFileTestDataDropDown.Value = {};
            app.SelectedChannelLabel_5 = uilabel(app.DatasetPreparationPanel);
            app.SelectedChannelLabel_5.Text = 'Selected Channel:';
            app.DP_SelectedChannelTestValDatalDropDown = uidropdown(app.DatasetPreparationPanel);
            app.DP_SelectedChannelTestValDatalDropDown.Items = {};
            app.DP_SelectedChannelTestValDatalDropDown.ValueChangedFcn = createCallbackFcn(app, @DP_SelectedChannelTestValDatalDropDownValueChanged, true);
            app.DP_SelectedChannelTestValDatalDropDown.Value = {};
            app.SelectedFileLabel_5 = uilabel(app.DatasetPreparationPanel);
            app.SelectedFileLabel_5.Text = 'Selected File:';
            app.DP_SelectedFileTestValDataDropDown = uidropdown(app.DatasetPreparationPanel);
            app.DP_SelectedFileTestValDataDropDown.Items = {};
            app.DP_SelectedFileTestValDataDropDown.ValueChangedFcn = createCallbackFcn(app, @DP_SelectedFileTestValDataDropDownValueChanged, true);
            app.DP_SelectedFileTestValDataDropDown.Value = {};
            app.DP_DatasetSelectionPanel = uipanel(app.DatasetPreparationPanel);
            app.DP_DatasetSelectionPanel.Title = 'Dataset Selection';
            app.DP_DatasetAttributesLabel = uilabel(app.DP_DatasetSelectionPanel);
            app.DP_DatasetAttributesLabel.Text = 'Dataset Attributes:';
            app.DP_DatasetAttributesUITable = uitable(app.DP_DatasetSelectionPanel);
            app.DP_DatasetAttributesUITable.ColumnName = {'Channel'; 'Minimum'; 'Maximum'; 'Mean'; 'Standard Deviation'};
            app.DP_DatasetAttributesUITable.RowName = {};
            app.DP_LoadDataButton = uibutton(app.DP_DatasetSelectionPanel, 'push');
            app.DP_LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @DP_LoadDataButtonPushed, true);
            app.DP_LoadDataButton.Tooltip = {'Load the selected dataset'};
            app.DP_LoadDataButton.Text = 'Load Data';
            app.DP_BrowseDatasetButton = uibutton(app.DP_DatasetSelectionPanel, 'push');
            app.DP_BrowseDatasetButton.ButtonPushedFcn = createCallbackFcn(app, @DP_BrowseDatasetButtonPushed, true);
            app.DP_BrowseDatasetButton.Text = 'Browse...';
            app.DP_DatasetFolderLabel = uilabel(app.DP_DatasetSelectionPanel);
            app.DP_DatasetFolderLabel.Text = 'Dataset Folder:';
            app.DP_DatasetFolderEditField = uieditfield(app.DP_DatasetSelectionPanel, 'text');
            app.DP_DatasetFolderEditField.ValueChangedFcn = createCallbackFcn(app, @DP_DatasetFolderEditFieldValueChanged, true);
            app.SelectDatasetDropDownLabel = uilabel(app.DP_DatasetSelectionPanel);
            app.SelectDatasetDropDownLabel.Text = 'Select Dataset:';
            app.DP_SelectDatasetDropDown = uidropdown(app.DP_DatasetSelectionPanel);
            app.DP_SelectDatasetDropDown.Items = {};
            app.DP_SelectDatasetDropDown.Value = {};
            app.DP_DatasetProcessingPanel = uipanel(app.DatasetPreparationPanel);
            app.DP_DatasetProcessingPanel.Title = 'Data Processing';
            app.DP_RatioTestValDataEditField = uieditfield(app.DP_DatasetProcessingPanel, 'numeric');
            app.DP_RatioTestValDataEditField.Limits = [0.01 0.99];
            app.DP_RatioTestValDataEditField.ValueChangedFcn = createCallbackFcn(app, @DP_RatioTestValDataEditFieldValueChanged, true);
            app.DP_RatioTestValDataEditField.Enable = 'off';
            app.DP_RatioTestValDataEditField.Value = 0.2;
            app.DP_SplitTestSetForThresholdingCheckBox = uicheckbox(app.DP_DatasetProcessingPanel);
            app.DP_SplitTestSetForThresholdingCheckBox.ValueChangedFcn = createCallbackFcn(app, @DP_SplitTestSetForThresholdingCheckBoxValueChanged, true);
            app.DP_SplitTestSetForThresholdingCheckBox.Text = 'Split Test Set for Thresholding';
            app.DP_RatioTestValDataLabel = uilabel(app.DP_DatasetProcessingPanel);
            app.DP_RatioTestValDataLabel.Enable = 'off';
            app.DP_RatioTestValDataLabel.Text = 'Ratio:';
            app.DP_SplitTestSetForDynamicSwitchCheckBox = uicheckbox(app.DP_DatasetProcessingPanel);
            app.DP_SplitTestSetForDynamicSwitchCheckBox.ValueChangedFcn = createCallbackFcn(app, @DP_SplitTestSetForDynamicSwitchCheckBoxValueChanged, true);
            app.DP_SplitTestSetForDynamicSwitchCheckBox.Enable = 'off';
            app.DP_SplitTestSetForDynamicSwitchCheckBox.Text = 'Split Test Set for Dynamic Switch';
            app.DP_RatioTestSwitchEditField = uieditfield(app.DP_DatasetProcessingPanel, 'numeric');
            app.DP_RatioTestSwitchEditField.Limits = [0.01 0.99];
            app.DP_RatioTestSwitchEditField.ValueChangedFcn = createCallbackFcn(app, @DP_RatioTestSwitchEditFieldValueChanged, true);
            app.DP_RatioTestSwitchEditField.Enable = 'off';
            app.DP_RatioTestSwitchEditField.Value = 0.5;
            app.DP_RatioTestSwitchLabel = uilabel(app.DP_DatasetProcessingPanel);
            app.DP_RatioTestSwitchLabel.Enable = 'off';
            app.DP_RatioTestSwitchLabel.Text = 'Ratio:';
            app.DP_AugmentedTrainingCheckBock = uicheckbox(app.DP_DatasetProcessingPanel);
            app.DP_AugmentedTrainingCheckBock.ValueChangedFcn = createCallbackFcn(app, @DP_AugmentedTrainingCheckBockValueChanged, true);
            app.DP_AugmentedTrainingCheckBock.Visible = 'off';
            app.DP_AugmentedTrainingCheckBock.Text = 'Augmented Training';
            app.DP_AugmentationIntensitySlider = uislider(app.DP_DatasetProcessingPanel);
            app.DP_AugmentationIntensitySlider.ValueChangedFcn = createCallbackFcn(app, @DP_AugmentationIntensitySliderValueChanged, true);
            app.DP_AugmentationIntensitySlider.Visible = 'off';
            app.PreprocessingMethodLabel = uilabel(app.DP_DatasetProcessingPanel);
            app.PreprocessingMethodLabel.Text = 'Preprocessing Method:';
            app.DP_PreprocessingMethodDropDown = uidropdown(app.DP_DatasetProcessingPanel);
            app.DP_PreprocessingMethodDropDown.Items = {'none', 'normalization_0_1', 'standardization'};
            app.DP_PreprocessingMethodDropDown.ValueChangedFcn = createCallbackFcn(app, @DP_PreprocessingMethodDropDownValueChanged, true);
            app.DP_PreprocessingMethodDropDown.Value = 'none';
            app.AugmentationModeDropDownLabel = uilabel(app.DP_DatasetProcessingPanel);
            app.AugmentationModeDropDownLabel.Text = 'Augmentation Mode:';
            app.DP_AugmentationModeDropDown = uidropdown(app.DP_DatasetProcessingPanel);
            app.DP_AugmentationModeDropDown.Items = {'none', 'white_noise', 'random_walk', 'global_shift', 'attenuate_extremum'};
            app.DP_AugmentationModeDropDown.ValueChangedFcn = createCallbackFcn(app, @DP_AugmentationModeDropDownValueChanged, true);
            app.DP_AugmentationModeDropDown.Value = 'none';
            app.DP_AugmentationIntensityLabel = uilabel(app.DP_DatasetProcessingPanel);
            app.DP_AugmentationIntensityLabel.Visible = 'off';
            app.DP_AugmentationIntensityLabel.Text = 'Intensity';
            app.TrainingAndDetectionTab = uitab(app.MainPanel);
            app.TrainingAndDetectionTab.Title = '2 Training and Detection';
            app.TrainingAndDetectionPanel = uipanel(app.TrainingAndDetectionTab);
            app.TD_TrainingPanel = uipanel(app.TrainingAndDetectionPanel);
            app.TD_TrainingPanel.Title = 'Training';
            app.TD_ModelsListBox = uilistbox(app.TD_TrainingPanel);
            app.TD_ModelsListBox.Items = {};
            app.TD_ModelsListBox.Multiselect = 'on';
            app.TD_ModelsListBox.Value = {};
            app.TD_ExportModelsButton = uibutton(app.TD_TrainingPanel, 'push');
            app.TD_ExportModelsButton.ButtonPushedFcn = createCallbackFcn(app, @TD_ExportModelsButtonPushed, true);
            app.TD_ExportModelsButton.Text = 'Export Models';
            app.TD_ImportModelsButton = uibutton(app.TD_TrainingPanel, 'push');
            app.TD_ImportModelsButton.ButtonPushedFcn = createCallbackFcn(app, @TD_ImportModelsButtonPushed, true);
            app.TD_ImportModelsButton.Text = 'Import Models';
            app.SortbyLabel = uilabel(app.TD_TrainingPanel);
            app.SortbyLabel.Text = 'Rank by:';
            app.TD_SortByDropDown = uidropdown(app.TD_TrainingPanel);
            app.TD_SortByDropDown.Items = {};
            app.TD_SortByDropDown.ValueChangedFcn = createCallbackFcn(app, @TD_SortByDropDownValueChanged, true);
            app.TD_SortByDropDown.Value = {};
            app.TD_LoadConfigButton = uibutton(app.TD_TrainingPanel, 'push');
            app.TD_LoadConfigButton.ButtonPushedFcn = createCallbackFcn(app, @TD_LoadConfigButtonPushed, true);
            app.TD_LoadConfigButton.Text = 'Load Config';
            app.TD_LoadDefaultButton = uibutton(app.TD_TrainingPanel, 'push');
            app.TD_LoadDefaultButton.ButtonPushedFcn = createCallbackFcn(app, @TD_LoadDefaultButtonPushed, true);
            app.TD_LoadDefaultButton.Text = 'Load Default';
            app.TD_LoadManuallyButton = uibutton(app.TD_TrainingPanel, 'push');
            app.TD_LoadManuallyButton.ButtonPushedFcn = createCallbackFcn(app, @TD_LoadManuallyButtonPushed, true);
            app.TD_LoadManuallyButton.Text = 'Load Manually';
            app.TD_OptimizeCheckBox = uicheckbox(app.TD_TrainingPanel);
            app.TD_OptimizeCheckBox.Text = 'Optimize';
            app.TD_TrainingPlotsCheckBox = uicheckbox(app.TD_TrainingPanel);
            app.TD_TrainingPlotsCheckBox.Text = 'Training Plots';
            app.TD_TrainingPlotsCheckBox.Value = true;
            app.TD_FitSelectionButton = uibutton(app.TD_TrainingPanel, 'push');
            app.TD_FitSelectionButton.ButtonPushedFcn = createCallbackFcn(app, @TD_FitSelectionButtonPushed, true);
            app.TD_FitSelectionButton.Text = 'Fit Selection';
            app.TD_FitAllButton = uibutton(app.TD_TrainingPanel, 'push');
            app.TD_FitAllButton.ButtonPushedFcn = createCallbackFcn(app, @TD_FitAllButtonPushed, true);
            app.TD_FitAllButton.Text = 'Fit All';
            app.TD_DeleteAllButton = uibutton(app.TD_TrainingPanel, 'push');
            app.TD_DeleteAllButton.ButtonPushedFcn = createCallbackFcn(app, @TD_DeleteAllButtonPushed, true);
            app.TD_DeleteAllButton.Text = 'Delete All';
            app.TD_ExportConfigButton = uibutton(app.TD_TrainingPanel, 'push');
            app.TD_ExportConfigButton.ButtonPushedFcn = createCallbackFcn(app, @TD_ExportConfigButtonPushed, true);
            app.TD_ExportConfigButton.Text = 'Export Config';
            app.TD_TrainAndTestAllButton = uibutton(app.TD_TrainingPanel, 'push');
            app.TD_TrainAndTestAllButton.ButtonPushedFcn = createCallbackFcn(app, @TD_TrainAndTestAllButtonPushed, true);
            app.TD_TrainAndTestAllButton.Text = 'Train & Test All';
            app.TD_DetectionPanel = uipanel(app.TrainingAndDetectionPanel);
            app.TD_DetectionPanel.Title = 'Detection';
            app.TD_DetectionTabGroup = uitabgroup(app.TD_DetectionPanel);
            app.TD_PlotsTab = uitab(app.TD_DetectionTabGroup);
            app.TD_PlotsTab.Title = 'Plots';
            app.TD_PlotsAnomalyScoresUIAxes = uiaxes(app.TD_PlotsTab);
            title(app.TD_PlotsAnomalyScoresUIAxes, 'Anomaly Scores')
            xlabel(app.TD_PlotsAnomalyScoresUIAxes, 'Timestamp')
            ylabel(app.TD_PlotsAnomalyScoresUIAxes, 'Score')
            zlabel(app.TD_PlotsAnomalyScoresUIAxes, 'Z')
            app.TD_PlotsDataUIAxes = uiaxes(app.TD_PlotsTab);
            title(app.TD_PlotsDataUIAxes, 'Data + Anomalies')
            xlabel(app.TD_PlotsDataUIAxes, 'Timestamp')
            ylabel(app.TD_PlotsDataUIAxes, 'Value')
            zlabel(app.TD_PlotsDataUIAxes, 'Z')
            app.TD_PlotsScoresUITable = uitable(app.TD_PlotsTab);
            app.TD_PlotsScoresUITable.ColumnName = '';
            app.TD_PlotsScoresUITable.RowName = {};
            app.TD_DetectionShownForLabel = uilabel(app.TD_PlotsTab);
            app.TD_DetectionShownForLabel.Text = 'Detection Shown for: None';
            app.TD_DetectionPerformanceMetricsLabel = uilabel(app.TD_PlotsTab);
            app.TD_DetectionPerformanceMetricsLabel.Text = 'Detection Performance Metrics:';
            app.TD_TableTab = uitab(app.TD_DetectionTabGroup);
            app.TD_TableTab.Title = 'Scores (selected File)';
            app.TD_ScoresUITable = uitable(app.TD_TableTab);
            app.TD_ScoresUITable.ColumnName = {'Metric'};
            app.TD_ScoresUITable.RowName = {};
            app.TD_ScoresExportTableButton = uibutton(app.TD_TableTab, 'push');
            app.TD_ScoresExportTableButton.ButtonPushedFcn = createCallbackFcn(app, @TD_ScoresExportTableButtonPushed, true);
            app.TD_ScoresExportTableButton.Text = 'Export Table';
            app.TD_AverageScoresTab = uitab(app.TD_DetectionTabGroup);
            app.TD_AverageScoresTab.Title = 'Average Scores';
            app.TD_AverageScoresUITable = uitable(app.TD_AverageScoresTab);
            app.TD_AverageScoresUITable.ColumnName = {'Metric'};
            app.TD_AverageScoresUITable.RowName = {};
            app.TD_AverageScoresExportTableButton = uibutton(app.TD_AverageScoresTab, 'push');
            app.TD_AverageScoresExportTableButton.ButtonPushedFcn = createCallbackFcn(app, @TD_AverageScoresExportTableButtonPushed, true);
            app.TD_AverageScoresExportTableButton.Text = 'Export Table';
            app.TD_WindowComputationTimeTab = uitab(app.TD_DetectionTabGroup);
            app.TD_WindowComputationTimeTab.Title = 'Window Computation Time';
            app.TD_WindowComputationTimeUIAxes = uiaxes(app.TD_WindowComputationTimeTab);
            title(app.TD_WindowComputationTimeUIAxes, 'Mean Window Computation Time')
            xlabel(app.TD_WindowComputationTimeUIAxes, 'Models sorted')
            ylabel(app.TD_WindowComputationTimeUIAxes, 'Metric value')
            zlabel(app.TD_WindowComputationTimeUIAxes, 'Z')
            app.TP_WindowComputationTimeMetricsTree = uitree(app.TD_WindowComputationTimeTab, 'checkbox');
            % Assign Checked Nodes
            app.TP_WindowComputationTimeMetricsTree.CheckedNodesChangedFcn = createCallbackFcn(app, @TP_WindowComputationTimeMetricsTreeCheckedNodesChanged, true);
            app.TD_WindowComputationTimeMetricsLabel = uilabel(app.TD_WindowComputationTimeTab);
            app.TD_WindowComputationTimeMetricsLabel.Text = 'Metrics';
            app.TD_FileSelectionButtonGroup = uibuttongroup(app.TD_DetectionPanel);
            app.TD_FileSelectionButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @TD_FileSelectionButtonGroupSelectionChanged, true);
            app.TD_FileSelectionButtonGroup.Title = 'File Selection';
            app.TD_FileSelectionTinyDataPreviewUIAxes = uiaxes(app.TD_FileSelectionButtonGroup);
            app.TD_FileSelectionTinyDataPreviewUIAxes.XTick = [];
            app.TD_FileSelectionTinyDataPreviewUIAxes.YTick = [];
            app.TD_FileSelectionTinyDataPreviewUIAxes.Visible = 'off';
            app.TD_FileSelectionAllFilesButton = uiradiobutton(app.TD_FileSelectionButtonGroup);
            app.TD_FileSelectionAllFilesButton.Text = 'All Files';
            app.TD_FileSelectionAllFilesButton.Value = true;
            app.TD_FileSelectionSelectionButton = uiradiobutton(app.TD_FileSelectionButtonGroup);
            app.TD_FileSelectionSelectionButton.Text = 'Selection';
            app.TD_FileSelectionFileLabel = uilabel(app.TD_FileSelectionButtonGroup);
            app.TD_FileSelectionFileLabel.Enable = 'off';
            app.TD_FileSelectionFileLabel.Text = 'File:';
            app.TD_FileSelectionFileDropDown = uidropdown(app.TD_FileSelectionButtonGroup);
            app.TD_FileSelectionFileDropDown.Items = {};
            app.TD_FileSelectionFileDropDown.ValueChangedFcn = createCallbackFcn(app, @TD_FileSelectionFileDropDownValueChanged, true);
            app.TD_FileSelectionFileDropDown.Enable = 'off';
            app.TD_FileSelectionFileDropDown.Value = {};
            app.TD_RunDetectionButton = uibutton(app.TD_DetectionPanel, 'push');
            app.TD_RunDetectionButton.ButtonPushedFcn = createCallbackFcn(app, @TD_RunDetectionButtonPushed, true);
            app.TD_RunDetectionButton.Text = {'Run'; 'Detection'};
            app.ThresholdDropDownLabel = uilabel(app.TD_DetectionPanel);
            app.ThresholdDropDownLabel.Text = 'Threshold:';
            app.TD_ThresholdDropDown = uidropdown(app.TD_DetectionPanel);
            app.TD_ThresholdDropDown.Items = {};
            app.TD_ThresholdDropDown.ValueChangedFcn = createCallbackFcn(app, @TD_ThresholdDropDownValueChanged, true);
            app.TD_ThresholdDropDown.Tooltip = {'The threshold used for converting anomaly scores into binary labels. To enable other thresholds, got to the Settings > Threshold Selection'};
            app.TD_ThresholdDropDown.Value = {};
            app.TD_ModelSelectionButtonGroup = uibuttongroup(app.TD_DetectionPanel);
            app.TD_ModelSelectionButtonGroup.Title = 'Model Selection';
            app.TD_ModelSelectionAllButton = uiradiobutton(app.TD_ModelSelectionButtonGroup);
            app.TD_ModelSelectionAllButton.Text = 'All';
            app.TD_ModelSelectionAllButton.Value = true;
            app.TD_ModelSelectionSelectionButton = uiradiobutton(app.TD_ModelSelectionButtonGroup);
            app.TD_ModelSelectionSelectionButton.Text = 'Selection';
            app.TD_GetWindowComputationTimeCheckBox = uicheckbox(app.TD_DetectionPanel);
            app.TD_GetWindowComputationTimeCheckBox.Text = 'Get Window Computation Time';
            app.TD_UpdateThresholdOnlyCheckBox = uicheckbox(app.TD_DetectionPanel);
            app.TD_UpdateThresholdOnlyCheckBox.Text = 'Update Threshold Only';
            app.TD_OnlineSimulationCheckBox = uicheckbox(app.TD_DetectionPanel);
            app.TD_OnlineSimulationCheckBox.Text = 'Online Simulation';
            app.TD_DynamicThresholdSettingsPanel = uipanel(app.TD_DetectionPanel);
            app.TD_DynamicThresholdSettingsPanel.Visible = 'off';
            app.TD_ZRangeLabel = uilabel(app.TD_DynamicThresholdSettingsPanel);
            app.TD_ZRangeLabel.Text = 'Z-Range:';
            app.TD_WindowSizeLabel = uilabel(app.TD_DynamicThresholdSettingsPanel);
            app.TD_WindowSizeLabel.Text = 'Window Size (%):';
            app.TD_AnomalyPaddingLabel = uilabel(app.TD_DynamicThresholdSettingsPanel);
            app.TD_AnomalyPaddingLabel.Text = 'Anomaly Padding:';
            app.TD_ZRangeSlider = uislider(app.TD_DynamicThresholdSettingsPanel);
            app.TD_ZRangeSlider.Limits = [1 10];
            app.TD_ZRangeSlider.ValueChangedFcn = createCallbackFcn(app, @TD_ZRangeSliderValueChanged, true);
            app.TD_ZRangeSlider.Value = 1;
            app.TD_WindowSizeSlider = uislider(app.TD_DynamicThresholdSettingsPanel);
            app.TD_WindowSizeSlider.Limits = [0 50];
            app.TD_WindowSizeSlider.ValueChangedFcn = createCallbackFcn(app, @TD_WindowSizeSliderValueChanged, true);
            app.TD_AnomalyPaddingSlider = uislider(app.TD_DynamicThresholdSettingsPanel);
            app.TD_AnomalyPaddingSlider.Limits = [0 1000];
            app.TD_AnomalyPaddingSlider.ValueChangedFcn = createCallbackFcn(app, @TD_AnomalyPaddingSliderValueChanged, true);
            app.DynamicSwitchTab = uitab(app.MainPanel);
            app.DynamicSwitchTab.Title = '3 Dynamic Switch';
            app.DynamicSwitchPanel = uipanel(app.DynamicSwitchTab);
            app.DS_TestSwitchDataPreviewUIAxes = uiaxes(app.DynamicSwitchPanel);
            title(app.DS_TestSwitchDataPreviewUIAxes, 'Test Switch Dataset (processed)')
            xlabel(app.DS_TestSwitchDataPreviewUIAxes, 'Timestamp')
            ylabel(app.DS_TestSwitchDataPreviewUIAxes, 'Value')
            zlabel(app.DS_TestSwitchDataPreviewUIAxes, 'Z')
            app.DS_ResultsUITable = uitable(app.DynamicSwitchPanel);
            app.DS_ResultsUITable.ColumnName = '';
            app.DS_ResultsUITable.RowName = {};
            app.DS_ResultsLabel = uilabel(app.DynamicSwitchPanel);
            app.DS_ResultsLabel.Text = 'Results:';
            app.DS_TrainingAndEvaluationPanel = uipanel(app.DynamicSwitchPanel);
            app.DS_TrainingAndEvaluationPanel.Tooltip = {'This panel can be used to train and test a classification network which can select the optimal model for each file of the testing dataset.'};
            app.DS_TrainingAndEvaluationPanel.Title = 'Training and Evaluation';
            app.DS_EvaluateButton = uibutton(app.DS_TrainingAndEvaluationPanel, 'push');
            app.DS_EvaluateButton.ButtonPushedFcn = createCallbackFcn(app, @DS_EvaluateButtonPushed, true);
            app.DS_EvaluateButton.Enable = 'off';
            app.DS_EvaluateButton.Text = 'Evaluate';
            app.DS_FitButton = uibutton(app.DS_TrainingAndEvaluationPanel, 'push');
            app.DS_FitButton.ButtonPushedFcn = createCallbackFcn(app, @DS_FitButtonPushed, true);
            app.DS_FitButton.Enable = 'off';
            app.DS_FitButton.Text = 'Fit';
            app.DS_TrainingDataLabelsUITable = uitable(app.DS_TrainingAndEvaluationPanel);
            app.DS_TrainingDataLabelsUITable.ColumnName = {'File'; 'Best Model'};
            app.DS_TrainingDataLabelsUITable.RowName = {};
            app.DS_TrainingDataLabelsLabel = uilabel(app.DS_TrainingAndEvaluationPanel);
            app.DS_TrainingDataLabelsLabel.Text = 'Training Data Labels';
            app.DS_TestingDataPredictionsUITable = uitable(app.DS_TrainingAndEvaluationPanel);
            app.DS_TestingDataPredictionsUITable.ColumnName = {'File'; 'Best Model'};
            app.DS_TestingDataPredictionsUITable.RowName = {};
            app.DS_TestingDataPredictionsLabel = uilabel(app.DS_TrainingAndEvaluationPanel);
            app.DS_TestingDataPredictionsLabel.Text = 'Testing Data Predictions';
            app.DS_ExportButton = uibutton(app.DynamicSwitchPanel, 'push');
            app.DS_ExportButton.ButtonPushedFcn = createCallbackFcn(app, @DS_ExportButtonPushed, true);
            app.DS_ExportButton.Text = 'Export Table';
            app.SelectedChannelLabel_6 = uilabel(app.DynamicSwitchPanel);
            app.SelectedChannelLabel_6.Text = 'Selected Channel:';
            app.DS_SelectedChannelTestSwitchDataDropDown = uidropdown(app.DynamicSwitchPanel);
            app.DS_SelectedChannelTestSwitchDataDropDown.Items = {};
            app.DS_SelectedChannelTestSwitchDataDropDown.ValueChangedFcn = createCallbackFcn(app, @DS_SelectedChannelTestSwitchDataDropDownValueChanged, true);
            app.DS_SelectedChannelTestSwitchDataDropDown.Value = {};
            app.SelectedFileLabel_6 = uilabel(app.DynamicSwitchPanel);
            app.SelectedFileLabel_6.Text = 'Selected File:';
            app.DS_SelectedFileTestSwitchDataDropDown = uidropdown(app.DynamicSwitchPanel);
            app.DS_SelectedFileTestSwitchDataDropDown.Items = {};
            app.DS_SelectedFileTestSwitchDataDropDown.ValueChangedFcn = createCallbackFcn(app, @DS_SelectedFileTestSwitchDataDropDownValueChanged, true);
            app.DS_SelectedFileTestSwitchDataDropDown.Value = {};
            app.TD_ContextMenu = uicontextmenu(app.TSADPlatformUIFigure);
            app.TD_ViewUpdateModelParametersMenu = uimenu(app.TD_ContextMenu);
            app.TD_ViewUpdateModelParametersMenu.MenuSelectedFcn = createCallbackFcn(app, @TD_ViewUpdateModelParametersMenuSelected, true);
            app.TD_ViewUpdateModelParametersMenu.Text = 'View/Update Model Parameters';
            app.TD_ShowNetworkArchitectureMenu = uimenu(app.TD_ContextMenu);
            app.TD_ShowNetworkArchitectureMenu.MenuSelectedFcn = createCallbackFcn(app, @TD_ShowNetworkArchitectureMenuSelected, true);
            app.TD_ShowNetworkArchitectureMenu.Text = 'Show Network Architecture';
            app.TD_ShowDetectionMenu = uimenu(app.TD_ContextMenu);
            app.TD_ShowDetectionMenu.MenuSelectedFcn = createCallbackFcn(app, @TD_ShowDetectionMenuSelected, true);
            app.TD_ShowDetectionMenu.Text = 'Show Detection';
            app.TD_RecomputeSupervisedStaticThresholdsMenu = uimenu(app.TD_ContextMenu);
            app.TD_RecomputeSupervisedStaticThresholdsMenu.MenuSelectedFcn = createCallbackFcn(app, @TD_RecomputeSupervisedStaticThresholdsMenuSelected, true);
            app.TD_RecomputeSupervisedStaticThresholdsMenu.Text = 'Recompute Supervised Static Thresholds';
            app.TD_DeleteMenu = uimenu(app.TD_ContextMenu);
            app.TD_DeleteMenu.MenuSelectedFcn = createCallbackFcn(app, @TD_DeleteMenuSelected, true);
            app.TD_DeleteMenu.Text = 'Delete';
            % Assign app.TD_ContextMenu
            app.TD_ModelsListBox.ContextMenu = app.TD_ContextMenu;
            % Show the figure after all components are created
            app.TSADPlatformUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TSADPlatform

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.TSADPlatformUIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.TSADPlatformUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.TSADPlatformUIFigure)
        end
    end
end